classdef LesionSynthesisToolbox < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LesionSynthesisToolboxUIFigure  matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Image2                          matlab.ui.control.Image
        btn_logout                      matlab.ui.control.Button
        btn_newsim                      matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        ProcessingServerRunningLamp     matlab.ui.control.Lamp
        ProcessingServerRunningLampLabel  matlab.ui.control.Label
        MessageLamp                     matlab.ui.control.Lamp
        MessageLampLabel                matlab.ui.control.Label
        SystemIDTextbox                 matlab.ui.control.TextArea
        HeadTabGroup                    matlab.ui.container.TabGroup
        LoginTab                        matlab.ui.container.Tab
        PasswordEditField               matlab.ui.control.EditField
        PasswordEditFieldLabel          matlab.ui.control.Label
        UsernameEditField               matlab.ui.control.EditField
        UsernameEditFieldLabel          matlab.ui.control.Label
        LoginButton                     matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        DiscoveryDRTab                  matlab.ui.container.Tab
        DateRangeDropDown               matlab.ui.control.DropDown
        DateRangeDropDownLabel          matlab.ui.control.Label
        StudyInvestigatorLabel          matlab.ui.control.Label
        AnonymizeImportedStudiesCheckBox  matlab.ui.control.CheckBox
        ImportSelectedStudiesButton     matlab.ui.control.Button
        ScannerDropDown                 matlab.ui.control.DropDown
        ScannerDropDownLabel            matlab.ui.control.Label
        ConnectButton                   matlab.ui.control.Button
        seriesImportTable               matlab.ui.control.Table
        rawImportTable                  matlab.ui.control.Table
        DiscoveryIRTab                  matlab.ui.container.Tab
        DeleteRawData                   matlab.ui.control.Button
        AnonymizeInplaceCheckBox        matlab.ui.control.CheckBox
        AnonymizeRawDataButton          matlab.ui.control.Button
        RemoveJobButton                 matlab.ui.control.Button
        StartProcessingServiceButton_2  matlab.ui.control.StateButton
        ImageReconstructionJobQueueListBox  matlab.ui.control.ListBox
        ImageReconstructionJobQueueLabel  matlab.ui.control.Label
        rawArchiveTable                 matlab.ui.control.Table
        AvailableStudiesLabel           matlab.ui.control.Label
        QueueImageReconstructionButton  matlab.ui.control.Button
        btn_saveDISettings_3            matlab.ui.control.Button
        RawReconProfilesDropDown        matlab.ui.control.DropDown
        ReconProfilesDropDownLabel      matlab.ui.control.Label
        RawReconParamsTable             matlab.ui.control.Table
        DiscoveryVOETab                 matlab.ui.container.Tab
        VOETabGroup                     matlab.ui.container.TabGroup
        SelectPatientTab                matlab.ui.container.Tab
        ReconSendToDICOMButton          matlab.ui.control.Button
        RefreshAvailReconsButton        matlab.ui.control.Button
        ScanPreviewPanel                matlab.ui.container.Panel
        spColorLimitSlider              matlab.ui.control.Slider
        Slider_3Label                   matlab.ui.control.Label
        SelectStudyButton               matlab.ui.control.Button
        SliceX                          matlab.ui.control.UIAxes
        SliceZ                          matlab.ui.control.UIAxes
        SliceY                          matlab.ui.control.UIAxes
        ReconstructedPatientStudiesLabel  matlab.ui.control.Label
        AvailReconsTable                matlab.ui.control.Table
        DefineDiseaseTab                matlab.ui.container.Tab
        DefineReferenceObjectPanel      matlab.ui.container.Panel
        RemoveRefObjectButton           matlab.ui.control.Button
        RefObjectsListBox               matlab.ui.control.ListBox
        AddRefObjectButton              matlab.ui.control.Button
        RefRadiusmmEditField            matlab.ui.control.NumericEditField
        RadiusmmEditField_2Label        matlab.ui.control.Label
        RefShapeDropDown                matlab.ui.control.DropDown
        ShapeDropDown_2Label            matlab.ui.control.Label
        RefObjectLocationEditField      matlab.ui.control.EditField
        ObjectLocationEditField_2Label  matlab.ui.control.Label
        RefObjectNameEditField          matlab.ui.control.EditField
        ObjectNameEditField_2Label      matlab.ui.control.Label
        SimulationSettingsPanel         matlab.ui.container.Panel
        KeeplesionsCheckBox             matlab.ui.control.CheckBox
        BedRangeDropDown                matlab.ui.control.DropDown
        BedRangeDropDownLabel           matlab.ui.control.Label
        SimulationReconProfileDropDown  matlab.ui.control.DropDown
        ImageReconstructionProfileLabel  matlab.ui.control.Label
        SimulationReconParamsTable      matlab.ui.control.Table
        QueueSimButton                  matlab.ui.control.Button
        DiseaseObjectstoSimulatePanel   matlab.ui.container.Panel
        RemoveObjectButton              matlab.ui.control.Button
        SimulationObjectsListBox        matlab.ui.control.ListBox
        DefineDiseaseObjectPanel        matlab.ui.container.Panel
        VarianceSlider                  matlab.ui.control.Slider
        VarianceSliderLabel             matlab.ui.control.Label
        CTIntensityAddModeDropDown      matlab.ui.control.DropDown
        PETIntensityAddModeDropDown     matlab.ui.control.DropDown
        AddObjectButton                 matlab.ui.control.Button
        CTIntensityHUEditField          matlab.ui.control.NumericEditField
        CTIntensityHUEditFieldLabel     matlab.ui.control.Label
        PETIntensityEditField           matlab.ui.control.NumericEditField
        PETIntensityEditFieldLabel      matlab.ui.control.Label
        PETIntensityModeDropDown        matlab.ui.control.DropDown
        PETIntensityModeDropDownLabel   matlab.ui.control.Label
        RadiusmmEditField               matlab.ui.control.NumericEditField
        RadiusmmEditFieldLabel          matlab.ui.control.Label
        ShapeDropDown                   matlab.ui.control.DropDown
        ShapeDropDownLabel              matlab.ui.control.Label
        ObjectLocationEditField         matlab.ui.control.EditField
        ObjectLocationEditFieldLabel    matlab.ui.control.Label
        ObjectNameEditField             matlab.ui.control.EditField
        ObjectNameEditFieldLabel        matlab.ui.control.Label
        DVolumeViewerPanel              matlab.ui.container.Panel
        ReconstructionProfileLabel      matlab.ui.control.Label
        ddPETColormapDropDown           matlab.ui.control.DropDown
        PETColormapDropDownLabel        matlab.ui.control.Label
        ddFusionFactorSlider            matlab.ui.control.Slider
        FusionFactorSliderLabel         matlab.ui.control.Label
        ddColorLimitSlider              matlab.ui.control.Slider
        Label                           matlab.ui.control.Label
        PatientIDLabel                  matlab.ui.control.Label
        PatientNameLabel                matlab.ui.control.Label
        ddSliceX                        matlab.ui.control.UIAxes
        ddSliceZ                        matlab.ui.control.UIAxes
        ddSliceY                        matlab.ui.control.UIAxes
        JobQueueTab                     matlab.ui.container.Tab
        RefreshButton                   matlab.ui.control.Button
        RemoveSimJobBtn                 matlab.ui.control.Button
        StartProcessingServiceButton    matlab.ui.control.StateButton
        SimulationsinQueueLabel         matlab.ui.control.Label
        SimsInQueueTable                matlab.ui.control.Table
        ProofSimulationsTab             matlab.ui.container.Tab
        RetrieveLesionsButton           matlab.ui.control.Button
        DownloadlesionsummarytableButton  matlab.ui.control.Button
        SimulationSendToDICOMButton     matlab.ui.control.Button
        LesionParamsCopyToClipboardButton  matlab.ui.control.Button
        LocationunitsDropDown           matlab.ui.control.DropDown
        LocationunitsDropDownLabel      matlab.ui.control.Label
        OpenfilelocationButton          matlab.ui.control.Button
        LesionsLabel                    matlab.ui.control.Label
        ReferenceROIsLabel              matlab.ui.control.Label
        LesionTable                     matlab.ui.control.Table
        RefROITable                     matlab.ui.control.Table
        SimulationReviewPanel           matlab.ui.container.Panel
        rlPETColormapDropDown           matlab.ui.control.DropDown
        PETColormapDropDown_2Label      matlab.ui.control.Label
        rlFusionFactorSlider            matlab.ui.control.Slider
        FusionFactorSlider_2Label       matlab.ui.control.Label
        rlColorLimitSlider              matlab.ui.control.Slider
        Slider_3Label_2                 matlab.ui.control.Label
        rlSliceX                        matlab.ui.control.UIAxes
        rlSliceZ                        matlab.ui.control.UIAxes
        rlSliceY                        matlab.ui.control.UIAxes
        SimulatedPatientStudiesLabel    matlab.ui.control.Label
        AvailSimulationsTable           matlab.ui.control.Table
        ConfigureTab                    matlab.ui.container.Tab
        ConfigureTabGroup               matlab.ui.container.TabGroup
        GeneralTab                      matlab.ui.container.Tab
        SaveGeneralSettings             matlab.ui.control.Button
        SimulationServerPanel           matlab.ui.container.Panel
        SimulationQueueEditField        matlab.ui.control.EditField
        SimulationQueueEditFieldLabel   matlab.ui.control.Label
        SimulationQueueBrowseButton     matlab.ui.control.Button
        ReconstructionServerPanel       matlab.ui.container.Panel
        ImageReconQueueEditField        matlab.ui.control.EditField
        ImageReconQueueEditFieldLabel   matlab.ui.control.Label
        ImageReconQueueBrowseButton     matlab.ui.control.Button
        UserAccountsPanel               matlab.ui.container.Panel
        deleteUserButton                matlab.ui.control.Button
        addUserButton                   matlab.ui.control.Button
        UserAccountsTable               matlab.ui.control.Table
        ScannerSettingsPanel            matlab.ui.container.Panel
        ScannerParamsTable              matlab.ui.control.Table
        deleteScannerButton             matlab.ui.control.Button
        addScannerButton                matlab.ui.control.Button
        ProjectTab                      matlab.ui.container.Tab
        FavoriteProjectButton           matlab.ui.control.Button
        ProjectsExplanationText         matlab.ui.control.Label
        ProjectParmetersPanel           matlab.ui.container.Panel
        DatalabellingPanel              matlab.ui.container.Panel
        SeriesDescriptionEditField      matlab.ui.control.EditField
        SeriesDescriptionEditFieldLabel  matlab.ui.control.Label
        SimulationNameTemplateEditField  matlab.ui.control.EditField
        SimulationNameTemplateEditFieldLabel  matlab.ui.control.Label
        SimulationNameTemplateExplationText  matlab.ui.control.Label
        DisplaySettingsPanel            matlab.ui.container.Panel
        ImageCoordinateUnitsDropDown    matlab.ui.control.DropDown
        ImageCoordinateUnitsDropDownLabel  matlab.ui.control.Label
        ImageUnitsDropDown              matlab.ui.control.DropDown
        ImageUnitsDropDownLabel         matlab.ui.control.Label
        ProjectCommentsTextArea         matlab.ui.control.TextArea
        CommentsTextAreaLabel           matlab.ui.control.Label
        SaveProjectSettings             matlab.ui.control.Button
        ReconstructionOptionsPanel      matlab.ui.container.Panel
        DefaultReconProtocolDropDown    matlab.ui.control.DropDown
        DefaultReconstructionProtocolDropDownLabel  matlab.ui.control.Label
        ReconProtocolsDirEditField      matlab.ui.control.EditField
        ReconstructionProtocolDirectoryEditFieldLabel  matlab.ui.control.Label
        ReconProtocolsDirBrowseButton   matlab.ui.control.Button
        NotificationservicesPanel       matlab.ui.container.Panel
        TestPushbulletButton            matlab.ui.control.Button
        GotoPushbulletwebsiteButton     matlab.ui.control.Button
        PushbulletTockenEditField       matlab.ui.control.EditField
        PushbulletTockenEditFieldLabel  matlab.ui.control.Label
        StorageSettingsPanel            matlab.ui.container.Panel
        DICOMSendPanel                  matlab.ui.container.Panel
        DICOMSendTargetPortEditField    matlab.ui.control.NumericEditField
        TargetPortEditFieldLabel        matlab.ui.control.Label
        DICOMSendIncludeCTCheckBox      matlab.ui.control.CheckBox
        DICOMSendSourceAETEditField     matlab.ui.control.EditField
        SourceAETEditFieldLabel         matlab.ui.control.Label
        DICOMSendTargetAETEditField     matlab.ui.control.EditField
        TargetAETEditFieldLabel         matlab.ui.control.Label
        DICOMSendTargetHostNameEditField  matlab.ui.control.EditField
        TargetHostNameEditFieldLabel    matlab.ui.control.Label
        SimulationArchiveBrowseButton   matlab.ui.control.Button
        ImageReconArchiveBrowseButton   matlab.ui.control.Button
        RawImportBrowseButton           matlab.ui.control.Button
        SimulationArchiveEditField      matlab.ui.control.EditField
        SimulationArchiveEditFieldLabel  matlab.ui.control.Label
        ImageReconArchiveEditField      matlab.ui.control.EditField
        ImageReconArchiveEditFieldLabel  matlab.ui.control.Label
        RawImportArchiveEditField       matlab.ui.control.EditField
        RawImportArchiveEditFieldLabel  matlab.ui.control.Label
        ProjectslistforuserPanel        matlab.ui.container.Panel
        ShowProjectsDirButton           matlab.ui.control.Button
        DeleteProjectButton             matlab.ui.control.Button
        AddProjectButton                matlab.ui.control.Button
        ProjectsListBox                 matlab.ui.control.ListBox
        ReconTab                        matlab.ui.container.Tab
        ReconstructionProtocolLibraryNameLabel  matlab.ui.control.Label
        ReconTabExplanationText         matlab.ui.control.Label
        ReconProfileNamesListBox        matlab.ui.control.ListBox
        ProfileNamesListBoxLabel        matlab.ui.control.Label
        DeleteReconProfileButton        matlab.ui.control.Button
        AddReconProfileButton           matlab.ui.control.Button
        ProfileParametersPanel          matlab.ui.container.Panel
        RestoreReconProfileParamsButton  matlab.ui.control.Button
        SaveProfileButton               matlab.ui.control.Button
        ReconProfileParamasTable        matlab.ui.control.Table
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    properties (Access = public)
        appDataDir

        scanners % STRUCT (Scanner Credentials)
        ftpinfo % STRUCT (FTP Info/Object)

        consoleDB % STR (Console dir to search)

        databaseInfo % Struct of raw study info

        refROIInfo
        lesionInfo
    end

    properties (Access = private)
        user % STR (Username)
        project % STR (Name of the current user project)
        currentImgData % Current Display Volume Stored
        currentPatientID %
        defPETIntensityModes % Description
        rlImgData   % Description
        locationDisplayUnits = 'mm from center'; % Description
        imgWithLesionData % Description
        appRootDir % Description
        LSTServiceStatusCheckTimer % Timer to periodically check if the LST Processing Service is running
    end

    methods (Access = private)

        function sucessfulLogInFcn(app)
            d = uiprogressdlg(app.LesionSynthesisToolboxUIFigure,'Message','Loading user settings','Indeterminate','on'); % progress dialog box
            app.ProjectslistforuserPanel.Title = ['Projects list for ' app.user];
            items = strrep(listfiles('*.mat',projectDir(app)),'.mat','');
            if isempty(items)
                items = {app.project};
            end
            app.ProjectsListBox.Items = items;
            app.ProjectsListBox.Value = app.project;
            updateGeneralSettings(app)
            updateProjectSettings(app)
            app.ScannerDropDown.Items = app.ScannerParamsTable.Data.Name;
            app.HeadTabGroup.SelectedTab = app.DiscoveryDRTab;
            delete(d)
        end

        function updateUsersTable(app,usersData)
            if nargin<2
                load([app.appDataDir filesep 'UsersData.mat'],'usersData');
            end
            app.UserAccountsTable.Data = usersData;
            i = strcmpi(usersData.AccountName, app.user);
            if usersData.Administrator(i)
                status = 'on';
                % 				pos = app.UserAccountsTable.Position;
                % 				app.UserAccountsTable.Position = pos;
            else
                status = 'off';
            end
            app.UserAccountsPanel.Visible = status;
            h = findobj(app.GeneralTab.Children);
            for i = 1:length(h)
                if ~strcmp(h(i).Type,'uipanel')
                    h(i).Enable = status;
                end
            end
        end

        function updateGeneralSettings(app)
            if exist([app.appDataDir filesep 'GeneralSettings.mat'],'file')
                genData = load([app.appDataDir filesep 'GeneralSettings.mat']);
                app.ImageReconQueueEditField.Value = genData.reconQueueDir;
                app.SimulationQueueEditField.Value = genData.simulationQueueDir;
                app.ScannerParamsTable.Data = genData.scannerParams;
            else
                app.ImageReconQueueEditField.Value = 'C:\LST Temp\Recon Queue';
                app.SimulationQueueEditField.Value = 'C:\LST Temp\Simulatio Queue';
                app.ScannerParamsTable.Data = table({},{},{},{},{},'VariableNames',{'Name','IP','Username','Password','DataPath'});
            end
        end

        function updateProjectSettings(app, projectName)
            if nargin<2
                projectName = app.project;
            end
            projectFile = [projectDir(app) filesep projectName '.mat'];
            if ~exist(projectFile,'file')
                app.SimulationNameTemplateEditField.Value = 'LST %Count%';
                app.SeriesDescriptionEditField.Value = 'MAC LST';
                app.ImageCoordinateUnitsDropDown.Value = 'pixel';
                app.ImageUnitsDropDown.Value = 'SUV-bw [g/mL]';
                app.RawImportArchiveEditField.Value = '';
                app.ImageReconArchiveEditField.Value = '';
                app.SimulationArchiveEditField.Value = '';
                app.ReconProtocolsDirEditField.Value = [app.appDataDir filesep 'Reconstruction Protocols'];

                if exist([reconProfilesDir(app) filesep 'Default OSEM.mat'],'file')
                    app.DefaultReconProtocolDropDown.Items = {'Default OSEM'};
                    app.DefaultReconProtocolDropDown.Value = 'Default OSEM';
                else
                    files = listfiles('*.mat',reconProfilesDir(app));
                    if ~isempty(files)
                        app.DefaultReconProtocolDropDown.Items = {files{1}};
                        app.DefaultReconProtocolDropDown.Value = files{1};
                    else
                        app.DefaultReconProtocolDropDown.Items = {'No recon profiles defined'};
                        app.DefaultReconProtocolDropDown.Value = 'No recon profiles defined';
                    end
                end
                app.PushbulletTockenEditField.Value = '';
                app.ProjectCommentsTextArea.Value = '';
            else
                d = load(projectFile);
                if strcmp(d.displaySettings.imageUnits,'SUV')
                    d.displaySettings.imageUnits = 'SUV-bw [g/mL]';
                end
                app.project = projectName;
                app.SimulationNameTemplateEditField.Value = d.labeling.simulationNameTemplate;
                app.SeriesDescriptionEditField.Value = d.labeling.seriesDescription;
                app.ImageCoordinateUnitsDropDown.Value = d.displaySettings.locationUnits;
                app.ImageUnitsDropDown.Value = d.displaySettings.imageUnits;
                app.ProjectParmetersPanel.Title = [projectName ' Parameters'];
                app.RawImportArchiveEditField.Value = d.rawDataArchiveDir;
                app.ImageReconArchiveEditField.Value = d.reconImageArchiveDir;
                app.SimulationArchiveEditField.Value = d.simulationArchiveDir;
                if isfield(d,'DICOMSend')
                    app.DICOMSendTargetHostNameEditField.Value = d.DICOMSend.TargetHost;
                    app.DICOMSendTargetPortEditField.Value = d.DICOMSend.TargetPort;
                    app.DICOMSendTargetAETEditField.Value = d.DICOMSend.TargetAET;
                    app.DICOMSendSourceAETEditField.Value = d.DICOMSend.SourceAET;
                    app.DICOMSendIncludeCTCheckBox.Value = d.DICOMSend.IncludeCT;
                else
                    app.DICOMSendTargetHostNameEditField.Value = '';
                    app.DICOMSendTargetPortEditField.Value = 104;
                    app.DICOMSendTargetAETEditField.Value = '';
                    app.DICOMSendSourceAETEditField.Value = 'LST_AET';
                    app.DICOMSendIncludeCTCheckBox.Value = true;
                end
                app.ReconProtocolsDirEditField.Value = d.reconProtocols.directory;
                app.DefaultReconProtocolDropDown.Items = {d.reconProtocols.defaultProtocol}; % temporary list unctil updateReconProtocolsList gets called
                app.DefaultReconProtocolDropDown.Value = d.reconProtocols.defaultProtocol;
                app.PushbulletTockenEditField.Value = d.notification.Pushbullet.Tocken;
                app.ProjectCommentsTextArea.Value = d.comments;
            end
            updateReconProtocolsList(app)
            DefaultReconProtocolDropDownValueChanged(app, []);

            i = strcmpi(app.UserAccountsTable.Data.AccountName, app.user);
            if strcmpi(app.UserAccountsTable.Data.Project{i}, app.project)
                app.FavoriteProjectButton.Icon = [app.appRootDir filesep 'Graphics' filesep 'litstarIcon_transp.png'];
                app.FavoriteProjectButton.Tooltip = 'This your default project';
            else
                app.FavoriteProjectButton.Icon = [app.appRootDir filesep 'Graphics' filesep 'starIcon_transp.png'];
                app.FavoriteProjectButton.Tooltip = 'Make this your default project';
            end
        end

        function updateReconProtocolsList(app,defaultValueFlag)
            protocolDir = reconProfilesDir(app);
            items = listfiles('*.mat',protocolDir);
            if isempty(items)
                params = defaultReconParams;
                items = {'Default OSEM.mat'};
                if exist(protocolDir,'dir') ~= 7
                    mkdir(protocolDir);
                end
                save([protocolDir filesep items{1}], 'params');
            end

            items = strrep(items,'.mat','');
            app.DefaultReconProtocolDropDown.Items = items;
            app.ReconProfileNamesListBox.Items = items;
            app.RawReconProfilesDropDown.Items = items;
            app.SimulationReconProfileDropDown.Items = items;
            if nargin<2 || defaultValueFlag % Select the default protocol
                updateReconParamTable(app,app.RawReconParamsTable)
                updateReconParamTable(app,app.SimulationReconParamsTable)
                updateReconParamTable(app,app.ReconProfileParamasTable)
            else
                updateReconParamTable(app,app.RawReconParamsTable, app.RawReconProfilesDropDown.Value)
                updateReconParamTable(app,app.SimulationReconParamsTable, app.SimulationReconProfileDropDown.Value)
                updateReconParamTable(app,app.ReconProfileParamasTable, app.ReconProfileNamesListBox.Value)
            end
        end

        function updateReconParamTable(app, tableHandle, reconProfileName, additionalParams)
            if nargin<3 || isempty(reconProfileName)
                reconProfileName = app.DefaultReconProtocolDropDown.Value;
            end
            reconProtocolFile = [reconProfilesDir(app) filesep reconProfileName '.mat'];
            if exist(reconProtocolFile,'file')
                load(reconProtocolFile,'params');
                switch tableHandle
                    case app.RawReconParamsTable
                        params.SimName = reconProfileName;
                        %                      if nargin>=4
                        %                          params.SimName = [additionalParams.rawDataSource ' - Baseline - ' reconProfileName];
                        %                      else
                        %                          params.SimName = ['Baseline - ' reconProfileName];
                        %                      end
                        params.SeriesDesc = 'LST Baseline Reconstruction';
                    case app.SimulationReconParamsTable
                        params.SimName = resolveName(app, app.SimulationNameTemplateEditField.Value);
                        params.SeriesDesc = resolveName(app, app.SeriesDescriptionEditField.Value);
                    case app.ReconProfileParamasTable
                        % do nothing
                    otherwise
                        warning('Unexpected table handle')
                end
                if nargin>=4
                    params = completeStructData(additionalParams,params);
                end
                params = completeStructData(params,defaultReconParams);
                col_1 = fieldnames(params);
                col_2 = struct2cell(params);
                tableHandle.Data = [col_1, col_2];
            else
                tableHandle.Data = {};
            end
        end


        % Updates the estimated image of the volume with the lesions added.
        function updateLesionVol(app)
            lesionData.refROI = app.refROIInfo;
            lesionData.lesion = app.lesionInfo;
            baselineImgData = app.currentImgData;
            lesionData.info.simParams.nXdim = baselineImgData.hdr.xdim;
            lesionData.info.simParams.nYdim = baselineImgData.hdr.ydim;
            lesionImgData = makeLesionImage(baselineImgData, lesionData); % TO DO: can use Lesion Margins to forecast contrast, CNR and other stats
            app.imgWithLesionData.vol = app.currentImgData.vol + lesionImgData.vol;
            plot_3D_volume(app, 'VOE', app.imgWithLesionData.currentXYZ)
        end

        function clearSimulationTab(app)
            % Clear 3D Volume Viewer
            app.DVolumeViewerPanel.Title = '3D Volume Viewer';
            cla(app.ddSliceX)
            cla(app.ddSliceY)
            cla(app.ddSliceZ)

            % Reset Define Object
            app.ObjectNameEditField.Value = 'Object_1';
            app.ObjectLocationEditField.Value = '';
            app.RadiusmmEditField.Value = 10;
            app.PETIntensityModeDropDown.Items = app.defPETIntensityModes;
            app.PETIntensityEditField.Value = 2.0;
            app.CTIntensityHUEditField.Value = 50;
            % Reset reference ROI Object
            app.RefObjectNameEditField.Value = 'Ref_1';
            app.RefRadiusmmEditField.Value = 20;
            app.RefObjectLocationEditField.Value = '';

            % Clear Objects to Simualte

            app.SimulationObjectsListBox.Items = {};
            app.RefObjectsListBox.Items = {};

            app.SimulationReconParamsTable.Data = [];
            app.lesionInfo = [];
            app.refROIInfo = [];
            % Reset Simulation Settings
        end


        function initializeUsers(app)
            usersData = table({'Administrator'},{'Admin'},{'admin1'},true,{'New Project'},'VariableNames',{'UserName','AccountName','Password','Administrator','Project'});
            uialert(app.LesionSynthesisToolboxUIFigure,'No user accounts have been configured. Please do so in the Configurations / General tab before continuing and then login again.','No user accounts');
            app.user = 'Admin';
            app.project = 'New Project';
            updateUsersTable(app,usersData);
            app.HeadTabGroup.SelectedTab = app.ConfigureTab;
            app.ConfigureTabGroup.SelectedTab = app.GeneralTab;
            sucessfulLogInFcn(app)
        end

        function result = projectDir(app)
            result = [app.appDataDir filesep 'User Project Settings' filesep app.user];
        end

        function saveUserData(app)
            usersData = app.UserAccountsTable.Data;
            save([app.appDataDir filesep 'UsersData.mat'],'usersData');
        end

        function protocolDir = reconProfilesDir(app)
            protocolDir = app.ReconProtocolsDirEditField.Value;
            if exist(protocolDir,'dir')~=7
                uialert(app.LesionSynthesisToolboxUIFigure,'The reconstruction profiles directory does not exist. Configure it in the Configure/Projects tab.','Missing reconstruction profiles directory')
                ReconProtocolsDirBrowseButtonPushed(app, '')
            end
        end

        function updateAvailRecons(app)
            d = uiprogressdlg(app.LesionSynthesisToolboxUIFigure,'Title','Available Reconstructed Studies (Local)',...
                'Message','Querying Local Directory');
            files = listfiles('*fIR3D.mat',app.ImageReconArchiveEditField.Value,'s');
            nStudies = length(files);
            data = cell(nStudies, 6);
            for i = 1:nStudies
                d.Value = i/nStudies;
                load([app.ImageReconArchiveEditField.Value filesep files{i}],'infodcm');
                if ischar(infodcm)
                    warning(['infodcm missing in ' app.ImageReconArchiveEditField.Value filesep files{i}])
                    p = fileparts([app.ImageReconArchiveEditField.Value filesep files{i}]);
                    [~, f] = fileparts(infodcm(1:end-1));
                    file = [p filesep f filesep '_bin1_sl1.sdcopen'];
                    infodcm = dicominfo(file);
                    save([app.ImageReconArchiveEditField.Value filesep files{i}],'-append','infodcm');
                end
                [~, reconName, ~] = fileparts(files{i});
                reconName = strrep(reconName,'_fIR3D','');
                patientID = fileparts(files{i});
                if ~strcmp(patientID, infodcm.PatientID)
                    warning(['Patient directory name doesn''t match patient ID (' infodcm.PatientID '): ' patientID]);
                end
                data(i, :) = {patientID, [infodcm.PatientName.FamilyName ', ' infodcm.PatientName.GivenName],...
                    infodcm.StudyDescription,...
                    [infodcm.AcquisitionDate(1:4) '-' infodcm.AcquisitionDate(5:6) '-' infodcm.AcquisitionDate(7:8)],...
                    reconName,...
                    datestr(infodcm.FileModDate,'yyyy-mm-dd HH:MM')};
            end
            data = sortrows(data,6,'descend');
            app.AvailReconsTable.Data = data;
            close(d)
        end

        % Get the next anonimization studies count
        function count = getAnonCount(app)
            file = [app.appDataDir filesep 'anonCount.mat'];
            if exist(file,'file')
                load(file,'count');
            else
                count = 1;
                save(file,'count');
            end
        end

        function count = incrementAnonCount(app)
            file = [app.appDataDir filesep 'anonCount.mat'];
            count = getAnonCount(app) + 1;
            save(file,'count');
        end

        function updateDiscoveryIRTab(app)

            rawStudies = listfolders(app.RawImportArchiveEditField.Value);
            j = 1;

            d = uiprogressdlg(app.LesionSynthesisToolboxUIFigure,'Title','Available Raw Studies (Local)',...
                'Indeterminate','on','Message','Querying Local Directory');

            for i = 1:size(rawStudies,2)
                if exist([app.RawImportArchiveEditField.Value filesep rawStudies{i} filesep 'CTAC'],'dir')
                    f = listfiles('*',[app.RawImportArchiveEditField.Value filesep rawStudies{i} filesep 'CTAC']);
                    if isempty(f)
                        disp(['No CTAC Files found for ' rawStudies{i}])
                        continue;
                    end
                    info = dicominfo([app.RawImportArchiveEditField.Value filesep rawStudies{i} filesep 'CTAC' filesep f{1}]);

                    if  isfield(info.PatientName,'GivenName') && isfield(info.PatientName,'FamilyName')
                        patientName{j} = [info.PatientName.FamilyName, ', ' info.PatientName.GivenName]; %#ok<AGROW>
                    elseif isfield(info.PatientName,'FamilyName')
                        patientName{j} = info.PatientName.FamilyName; %#ok<AGROW>
                    else
                        patientName{j} = 'N/A'; %#ok<AGROW>
                    end

                    ID{j} = info.PatientID; %#ok<AGROW>
                    studyDesc{j} = info.StudyDescription; %#ok<AGROW>
                    acqDate{j} = info.AcquisitionDate; %#ok<AGROW>
                    name{j} = rawStudies{i}; %#ok<AGROW>
                    j = j +1;
                end
            end

            if j>1
                data = [name',ID',patientName',studyDesc',acqDate'];
                data = sortrows(data,5,'descend');
                app.rawArchiveTable.Data = data;
            end
            close(d)

            % Load Recon Job Q
            files = listfiles('*_reconParams.mat', app.ImageReconQueueEditField.Value,'s');
            app.ImageReconstructionJobQueueListBox.Items = files';
        end


        function nameStr = resolveName(app, nameStr)
            patterns = {'%count%'};
            for pi = 1:length(patterns)
                pat = patterns{pi};
                indx = strfind(lower(nameStr), pat);
                for i=1:length(indx)
                    if i==1
                        switch lower(pat)
                            case '%count%'
                                newVal = sprintf('%05d',getProjectCount(app));
                            otherwise % should nver get here
                                error('Unknown pattern encountered')
                        end
                    end
                    nameStr = [nameStr(1:indx(i)-1) newVal nameStr(indx(i)+length(pat):end)];
                end
            end
        end

        function newVal = getProjectCount(app)
            files = listfiles(app.SimulationArchiveEditField.Value);
            newVal = length(files)+1;
        end

        function imgData = addCTvol(app, imgData, fpath)
            if ~exist([fpath filesep 'CTAC.mat'],'file')
                d = uiprogressdlg(app.LesionSynthesisToolboxUIFigure,'Message','Please wait while making CT mat file.','Indeterminate','on');
                makeCTmatFile([fpath filesep 'CTAC']);
                delete(d);
            end
            CTimgData = load([fpath filesep 'CTAC.mat']);
            % 			[XPET, YPET, ZPET] = meshgrid((0.5:imgData.hdr.xdim-0.5)*imgData.hdr.pix_mm_xy-imgData.hdr.image_offset_mm(1),...
            % 				(0.5:imgData.hdr.ydim-0.5)*imgData.hdr.pix_mm_xy-imgData.hdr.image_offset_mm(2),...
            % 				(0.5:imgData.hdr.nplanes-0.5)*imgData.hdr.pix_mm_z-imgData.hdr.image_offset_mm(3));
            % 			[XCT, YCT, ZCT] = meshgrid((0.5:CTimgData.hdr.xdim-0.5)*CTimgData.hdr.pix_mm_xy-CTimgData.hdr.image_offset_mm(1),...
            % 				(0.5:CTimgData.hdr.ydim-0.5)*CTimgData.hdr.pix_mm_xy-CTimgData.hdr.image_offset_mm(2),...
            % 				(0.5:CTimgData.hdr.nplanes-0.5)*CTimgData.hdr.pix_mm_z-CTimgData.hdr.image_offset_mm(3));
            [XPET, YPET, ZPET] = meshgrid((0.5:imgData.hdr.xdim-0.5)*imgData.hdr.pix_mm_xy,...
                (0.5:imgData.hdr.ydim-0.5)*imgData.hdr.pix_mm_xy,...
                (0.5:imgData.hdr.nplanes-0.5)*imgData.hdr.pix_mm_z);
            [XCT, YCT, ZCT] = meshgrid((0.5:CTimgData.hdr.xdim-0.5)*CTimgData.hdr.pix_mm_xy,...
                (0.5:CTimgData.hdr.ydim-0.5)*CTimgData.hdr.pix_mm_xy,...
                (0.5:CTimgData.hdr.nplanes-0.5)*CTimgData.hdr.pix_mm_z);
            imgData.CTvol = interp3(XCT, YCT, ZCT, double(CTimgData.vol), XPET, YPET, ZPET);
        end

        function updateSimulationJobQueue(app)
            j=0;
            folders = listfolders(app.SimulationQueueEditField.Value);
            if ~isempty(folders)
                for folderi = 1:length(folders)
                    tempdir = [app.SimulationQueueEditField.Value filesep folders{folderi}];
                    files = listfiles('*_LesionParams.mat', tempdir);
                    for filei = 1:length(files)
                        j=j+1;
                        temp = load([tempdir filesep files{filei}]);
                        simName{j} = temp.info.reconName; %#ok<AGROW>
                        patientID{j} = temp.info.patientID; %#ok<AGROW>
                        nObject{j} = length(temp.lesion); %#ok<AGROW>
                        tempDir{j} = tempdir; %#ok<AGROW>
                        archiveDir{j} = temp.info.simulationArchiveDir; %#ok<AGROW>
                    end
                end
            end
            if j==0
                app.SimsInQueueTable.Data = [];
            else
                app.SimsInQueueTable.Data = [simName',patientID',nObject',tempDir',archiveDir'];
            end
        end

        function result = isValidAppDataDir(app)
            result = exist(app.appDataDir,'dir') &&...
                exist([app.appDataDir filesep 'DataDirectories.mat'],'file');
            % TO DO: check for other files/directories?
        end

        function baseDir = initializeApp(app)
            drawnow;
            uialert(app.LesionSynthesisToolboxUIFigure,{'The application data directory is the root directory where all data will be stored by default. It contains the following sub-directories be default:' ...
                '   - Application settings' ...
                '   - Raw scan data (can be changed per project)',...
                '   - Reconstructed scan archive (can be changed per project)',...
                '   - Simulated archive (can be changed per project)',...
                '   - Reconstruction queue (can be changed per project)',...
                '   - Simulation queue (can be changed per project)',...
                '',...
                'Select the location for the directory Application Data directory. All the other directories will be created in it''s parent (one level up).' ...
                '' ...
                'Note: The data can be very large - we recommend selecting a drive with >1 TB capacity.'}, ...
                'Initialize application',...
                'Icon','info',...
                'Modal',true);
            baseDir0 = ['C:' filesep 'Lesion Synthesis Toolbox']; % default location for baseDataDirectory
            baseDir0Existed = exist(baseDir0,'dir')==7;
            if ~baseDir0Existed
                mkdir(baseDir0);
            end
            baseDir = uigetdir(baseDir0,'Application data base directory location');
            if isequal(baseDir,0)
                if ~baseDir0Existed
                    rmdir(baseDir0);
                end
            else
                if ~strcmpi(baseDir,baseDir0) && ~baseDir0Existed
                    rmdir(baseDir0);
                end
                if exist(baseDir,'dir')~=7
                    mkdir(baseDir)
                end
                app.appDataDir = [baseDir filesep 'Application Data'];
                if exist(app.appDataDir,'dir')~=7
                    mkdir(app.appDataDir)
                end

                sourceDir = [fileparts(mfilename('fullpath')) filesep 'Default Application Data' filesep];
                copyfile(sourceDir, app.appDataDir)

                count = 0;
                save([app.appDataDir filesep 'anonCount.mat'], 'count');
                save([app.appDataDir filesep 'numSims.mat'], 'count');

                % might not need to create scanner - can copy file
                scanners.NewScanner = struct('Username','username',....
                    'Password','password',...
                    'IP','0.0.0.0',...
                    'DataPath','/usr/g/sdc_image_pool/images/'); %#ok<ADPROP>
                save([app.appDataDir filesep 'Modalities.mat'], 'scanners');

                % default directories
                rawDataArchiveDir = [baseDir filesep 'Raw Data'];
                mkdir(rawDataArchiveDir);
                reconImageArchiveDir = [baseDir filesep 'Reconstructed Archive'];
                mkdir(reconImageArchiveDir);
                simulationArchiveDir = [baseDir filesep 'Simulation Archive'];
                mkdir(simulationArchiveDir);
                reconQueueDir = [baseDir filesep 'Recon Queue'];
                mkdir(reconQueueDir);
                simulationQueueDir = [baseDir filesep 'Simulation Queue'];
                mkdir(simulationQueueDir);
                save([app.appDataDir filesep 'DataDirectories.mat'], 'rawDataArchiveDir','reconImageArchiveDir','simulationArchiveDir','reconQueueDir','simulationQueueDir');

                % Create a default project file
                comments = {'Default project, generated during application initialization.',['Created on: ' datestr(now,31)]};
                displaySettings = struct('locationUnits','pixel',...
                    'imageUnits','SUV-bw [g/mL]');
                labeling = struct('simulationNameTemplate','LST %Count%',...
                    'seriesDescription','MAC LST');
                notification.Pushbullet.Tocken = '';
                reconProtocols = struct('directory', [app.appDataDir filesep 'Reconstruction Protocols'],...
                    'defaultProtocol','Default OSEM');
                save([app.appDataDir filesep 'User Project Settings' filesep 'Admin' filesep 'New Project.mat'],'comments','displaySettings','labeling','notification','rawDataArchiveDir','reconImageArchiveDir','simulationArchiveDir','reconProtocols');
                if exist([app.appDataDir filesep 'Reconstruction Protocols'],'dir') ~=7
                    mkdir([app.appDataDir filesep 'Reconstruction Protocols'])
                end

                if exist([app.appDataDir filesep 'Reconstruction Protocols' filesep 'Default OSEM.mat'],'file') ~=2
                    params = defaultReconParams;
                    save([app.appDataDir filesep 'Reconstruction Protocols' filesep 'Default OSEM.mat'],'params')
                end

                d.appDataDir = app.appDataDir;
                d.appNeverRun = false;
                save([app.appRootDir filesep 'AppStatus.mat'], 'd');
            end
        end

        function logout(app)
            app.HeadTabGroup.SelectedTab = app.LoginTab; % returns to login window
            app.UsernameEditField.Value = '';
            app.PasswordEditField.Value = '';
        end

        function retrieveLesions(app)


            indices = app.AvailSimulationsTable.UserData.Indices;

            app.currentPatientID = cell2mat(app.AvailSimulationsTable.Data(indices(1),1));
            currentReconName = cell2mat(app.AvailSimulationsTable.Data(indices(1),3));

            fpath = [app.ImageReconArchiveEditField.Value filesep, ...
                app.currentPatientID];

            if exist([fpath filesep currentReconName '.mat'],'file')
                imgFile = [fpath filesep currentReconName '.mat'];
            else
                imgFile = [fpath filesep currentReconName '_fIR3D.mat'];
            end
            h = uiprogressdlg(app.LesionSynthesisToolboxUIFigure,'Title','Loading original reconstructed image',...
                'Message',['Loading PET ' imgFile], 'Indeterminate','on');
            imgData = load(imgFile, 'vol','hdr');
            imgData.vol = applyUptakeUnits(imgData.vol, imgData.hdr, app.ImageUnitsDropDown.Value);
            h.Message = ['Loading CT for ' imgFile];
            imgData = addCTvol(app, imgData, fpath);
            plot_3D_volume(app,'VOE',[],imgData)
            app.ScanPreviewPanel.Title = ['Scan Preview: ' strjoin({imgData.hdr.patientID, imgData.hdr.patient_name, imgData.hdr.study, imgData.hdr.date, currentReconName},' | ')];
            close(h);

            ddColorLimitSliderValueChanging(app,app.rlColorLimitSlider.Value);

            intMode = app.defPETIntensityModes;

            for i = 1:size(app.RefROITable.Data, 1)
                app.RefObjectNameEditField.Value = cell2mat(app.RefROITable.Data(1));
                app.RefObjectLocationEditField.Value = cell2mat(app.RefROITable.Data(2));   % DOESN'T WORK PROPERLY
                app.RefShapeDropDown.Value = cell2mat(app.RefROITable.Data(3));
                rad1 = cell2mat(app.RefROITable.Data(4));
                app.RefRadiusmmEditField.Value = str2double(rad1(ismember(rad1, '0':'9')));

                AddRefObjectButtonPushed(app, '');

                % add reference to drop down list
                intMode = [intMode, strcat('Lesion:',cell2mat(app.RefROITable.Data(1)))];
            end

            app.PETIntensityModeDropDown.Items = intMode;

            itemCount = length(app.LesionTable.Data(:,1));
            locUnits = 'mm from center';
            items = cell((itemCount-1),1);

            % FIX to extract the values that are not displayed on the table use: lesionData.lesion{i,number}


            % add lesions to items list
            for i = 1:(itemCount-1)
                app.lesionInfo{i}.name = cell2mat(app.LesionTable.Data(i,1));
                app.lesionInfo{i}.loc = cell2mat(app.LesionTable.Data(i,2));
                app.lesionInfo{i}.locUnits = locUnits;
                lesShape = app.LesionTable.Data(i,6);
                shapes = app.ShapeDropDown.Items;

                app.lesionInfo{i}.shape = cell2mat(shapes(matches(shapes,lesShape, "IgnoreCase",true)));

                rad2 = cell2mat(app.LesionTable.Data(itemCount,8));
                app.lesionInfo{i}.rad = str2double(rad2(ismember(rad2, '0':'9')));
                app.lesionInfo{i}.PTval = cell2mat(app.LesionTable.Data(i,4));
                addMode = cell2mat(app.LesionTable.Data(i,7));
                modes = app.PETIntensityAddModeDropDown.Items;

                app.lesionInfo{i}.PTadditionMode = cell2mat(modes(matches(modes,addMode,"IgnoreCase",true)));

                app.lesionInfo{i}.CTval = cell2mat(app.LesionTable.Data(i,5));
                app.lesionInfo{i}.CTadditionMode = 'Final (Homogenous)'; % FIX THIS

                app.lesionInfo{i}.mode = cell2mat(intMode(matches(intMode,app.LesionTable.Data(i,3),"IgnoreCase",true)));

                app.lesionInfo{i}.hdr = imgData.hdr;
                app.lesionInfo{i}.var = 0; % FIX THIS
                items{i} = app.lesionInfo{i}.name;
            end

            % add lesions list to simulation objects list
            app.SimulationObjectsListBox.Items = items;

            % set the final lesion paramters to update the UI
            app.ObjectNameEditField.Value = cell2mat(app.LesionTable.Data(itemCount,1));
            app.ObjectLocationEditField.Value = cell2mat(app.LesionTable.Data(itemCount,2));
            lesShape = app.LesionTable.Data(itemCount,6);
            shapes = app.ShapeDropDown.Items;
            app.ShapeDropDown.Value = shapes(matches(shapes,lesShape, "IgnoreCase",true));
            rad2 = cell2mat(app.LesionTable.Data(itemCount,8));
            app.RadiusmmEditField.Value = str2double(rad2(ismember(rad2, '0':'9')));
            app.PETIntensityEditField.Value = cell2mat(app.LesionTable.Data(itemCount,4));
            app.CTIntensityHUEditField.Value = cell2mat(app.LesionTable.Data(itemCount,5));
            app.PETIntensityModeDropDown.Value = cell2mat(intMode(matches(intMode,app.LesionTable.Data(itemCount,3),"IgnoreCase",true)));

            addMode = cell2mat(app.LesionTable.Data(itemCount,7));
            modes = app.PETIntensityAddModeDropDown.Items;
            app.PETIntensityAddModeDropDown.Value = modes(matches(modes,addMode,"IgnoreCase",true));
            AddObjectButtonPushed(app,'');
        end
    end

    methods (Access = public)


        function plot_3D_volume(app,type,coord,imgData)

            if nargin==4 && ~isempty(imgData)
                imgData.maxXYZ = size(imgData.vol);
                imgData.maxVol = double(max(imgData.vol(:)));
                switch type
                    case 'reconPreview'
                        app.currentImgData = imgData;
                    case 'VOE'
                        app.imgWithLesionData = imgData;
                    case 'lesionReview'
                        app.rlImgData = imgData;
                end
                newImgFlag = true;
            else
                newImgFlag = false;
                switch type
                    case 'reconPreview'
                        imgData = app.currentImgData;
                    case 'VOE'
                        imgData = app.imgWithLesionData;
                    case 'lesionReview'
                        imgData = app.rlImgData;
                end
            end

            s = imgData.maxXYZ;

            if newImgFlag && (nargin<3 || isempty(coord))
                x = round(s(1)/2);
                y = round(s(2)/2);
                z = round(s(3)/2);
            elseif nargin<3 || isempty(coord)
                switch type
                    case 'reconPreview'
                        pos = app.currentImgData.currentXYZ;
                    case 'VOE'
                        pos = app.imgWithLesionData.currentXYZ;
                    case 'lesionReview'
                        pos = app.rlImgData.currentXYZ;
                end
                x = pos(1);
                y = pos(2);
                z = pos(3);
            else
                x = round(coord(1));
                y = round(coord(2));
                z = round(coord(3));
            end

            switch type
                case 'reconPreview'
                    app.currentImgData.currentXYZ = [x y z];
                    ax_x = app.SliceX;
                    xtag = 'rpSliceX';
                    ax_y = app.SliceY;
                    ytag = 'rpSliceY';
                    ax_z = app.SliceZ;
                    ztag = 'rpSliceZ';
                    slider = app.spColorLimitSlider;
                    alpha = 1;
                case 'VOE'
                    app.imgWithLesionData.currentXYZ = [x y z];
                    ax_x = app.ddSliceX;
                    xtag = 'voeSliceX';
                    ax_y = app.ddSliceY;
                    ytag = 'voeSliceY';
                    ax_z = app.ddSliceZ;
                    ztag = 'voeSliceZ';
                    slider = app.ddColorLimitSlider;
                    % x and y locations are transposed on purpose due to image
                    % reorientation for display
                    locationStr = mynum2str(convertImgLocationUnits(imgData.hdr, [y x z], 'pixel', app.locationDisplayUnits),3,0);
                    app.ObjectLocationEditField.Value = locationStr;
                    app.RefObjectLocationEditField.Value = locationStr;
                    alpha = app.ddFusionFactorSlider.Value;
                case 'lesionReview'
                    app.rlImgData.currentXYZ = [x y z];
                    ax_x = app.rlSliceX;
                    xtag = 'rlSliceX';
                    ax_y = app.rlSliceY;
                    ytag = 'rlSliceY';
                    ax_z = app.rlSliceZ;
                    ztag = 'rlSliceZ';
                    slider = app.rlColorLimitSlider;
                    alpha = app.rlFusionFactorSlider.Value;
            end

            if newImgFlag
                slider.Limits = [0 imgData.maxVol*1.5];
                if contains(app.ImageUnitsDropDown.Value,'SUV')
                    slider.Value = 7;
                else
                    slider.Value = imgData.maxVol;
                end
                slider.ValueChangingFcn(app,slider.Value)
            end

            PTLim = [0 slider.Value];
            CTLim = [-200,300];
            colordepth = 128;
            if alpha == 1 % PET only
                PTColormap = gray(colordepth);
            else
                switch type
                    case 'VOE'
                        PTColormap = feval(erase(lower(strtrim(app.ddPETColormapDropDown.Value)),' '), colordepth);
                    case 'lesionReview'
                        PTColormap = feval(erase(lower(strtrim(app.rlPETColormapDropDown.Value)),' '), colordepth);
                end
            end
            imgx = ind2rgb(gray2ind(mat2gray(squeeze(imgData.vol(x,:,:))', PTLim), colordepth),PTColormap);
            imgy = ind2rgb(gray2ind(mat2gray(squeeze(imgData.vol(:,y,:))', PTLim), colordepth),PTColormap);
            imgz = ind2rgb(gray2ind(mat2gray(squeeze(imgData.vol(:,:,z))', PTLim), colordepth),PTColormap);
            if alpha<1 % PET only
                CTColormap = gray(colordepth);
                CTimgx = ind2rgb(gray2ind(mat2gray(squeeze(imgData.CTvol(x,:,:))', CTLim), colordepth),CTColormap);
                CTimgy = ind2rgb(gray2ind(mat2gray(squeeze(imgData.CTvol(:,y,:))', CTLim), colordepth),CTColormap);
                CTimgz = ind2rgb(gray2ind(mat2gray(squeeze(imgData.CTvol(:,:,z))', CTLim), colordepth),CTColormap);
                imgx = imblend(imgx, CTimgx, alpha, 'normal');
                imgy = imblend(imgy, CTimgy, alpha, 'normal');
                imgz = imblend(imgz, CTimgz, alpha, 'normal');
            end
            %% TO DO - make this all object oriented
            if newImgFlag % Configure the axes on first use
                delete(ax_x.Children)
                delete(ax_y.Children)
                delete(ax_z.Children)
                ih = image(ax_x,imgx);
                ih.ButtonDownFcn = createCallbackFcn(app,@Imgbtndwn, true);
                ih.Tag = xtag;
                axis(ax_x,'off')
                axis(ax_x,'image')
                ax_x.BackgroundColor = 'white';
                ax_x.Title.String = '';
                hold(ih.Parent,'on')
                plot(ih.Parent, y*[1 1],[1 s(3)],'--red','ButtonDownFcn',createCallbackFcn(app,@Imgbtndwn, true),'tag','vertCrossHairLine');
                plot(ih.Parent, [1 s(2)],z*[1 1],'--red','ButtonDownFcn',createCallbackFcn(app,@Imgbtndwn, true),'tag','horzCrossHairLine');
                set(ih.Parent,'xlim',[0 s(2)])
                set(ih.Parent,'ylim',[0 s(3)])
                daspect(ax_x,[imgData.hdr.pix_mm_z, imgData.hdr.pix_mm_xy, 1])

                ih = image(ax_y,imgy);
                ih.ButtonDownFcn = createCallbackFcn(app,@Imgbtndwn, true);
                ih.Tag = ytag;
                axis(ax_y,'off')
                axis(ax_y,'image')
                ax_y.BackgroundColor = 'white';
                ax_y.Title.String = '';
                hold(ih.Parent,'on')
                plot(ih.Parent, x*[1 1],[1 s(3)],'--red','ButtonDownFcn',createCallbackFcn(app,@Imgbtndwn, true),'tag','vertCrossHairLine');
                plot(ih.Parent, [1 s(1)],z*[1 1],'--red','ButtonDownFcn',createCallbackFcn(app,@Imgbtndwn, true),'tag','horzCrossHairLine');
                set(ih.Parent,'xlim',[0 s(1)])
                set(ih.Parent,'ylim',[0 s(3)])
                daspect(ax_y,[imgData.hdr.pix_mm_z, imgData.hdr.pix_mm_xy, 1])

                ih = image(ax_z,imgz);
                ih.ButtonDownFcn = createCallbackFcn(app,@Imgbtndwn, true);
                ih.Tag = ztag;
                axis(ax_z,'off')
                axis(ax_z,'image')
                ax_z.BackgroundColor = 'white';
                ax_z.Title.String = '';
                hold(ih.Parent,'on')
                plot(ih.Parent, x*[1 1],[1 s(2)],'--red','ButtonDownFcn',createCallbackFcn(app,@Imgbtndwn, true),'tag','vertCrossHairLine');
                plot(ih.Parent, [1 s(1)],y*[1 1],'--red','ButtonDownFcn',createCallbackFcn(app,@Imgbtndwn, true),'tag','horzCrossHairLine');
                set(ih.Parent,'xlim',[round(s(1)*.15) round(s(1)*.85)])
                set(ih.Parent,'ylim',[round(s(2)*.15) round(s(2)*.85)])
                daspect(ax_z,[imgData.hdr.pix_mm_xy, imgData.hdr.pix_mm_xy, 1])

                ax_x.Toolbar.Visible = 'off';
                ax_y.Toolbar.Visible = 'off';
                ax_z.Toolbar.Visible = 'off';

            else % Axes have already been configured
                set(findobj(ax_x,'Type','Image'),'Cdata',imgx)
                set(findobj(ax_x,'Tag','vertCrossHairLine'),'xdata',y*[1 1])
                set(findobj(ax_x,'Tag','horzCrossHairLine'),'ydata',z*[1 1])

                set(findobj(ax_y,'Type','Image'),'Cdata',imgy)
                set(findobj(ax_y,'Tag','vertCrossHairLine'),'xdata',x*[1 1])
                set(findobj(ax_y,'Tag','horzCrossHairLine'),'ydata',z*[1 1])

                set(findobj(ax_z,'Type','Image'),'Cdata',imgz)
                set(findobj(ax_z,'Tag','vertCrossHairLine'),'xdata',x*[1 1])
                set(findobj(ax_z,'Tag','horzCrossHairLine'),'ydata',y*[1 1])
            end

        end

        function Imgbtndwn(app, event)
            h = event.Source;
            if ~strcmp(h.Type,'Image')
                h = findobj(h.Parent, 'Type','Image');
            end
            tag = h.Tag;
            coord = event.IntersectionPoint;

            if contains(tag,'voe')
                tag = tag(4:end);
                flag = 'VOE'; % virtual object editor
                temp = app.imgWithLesionData.currentXYZ;
            elseif contains(tag,'rp')
                tag = tag(3:end);
                flag = 'reconPreview'; % recon preview
                temp = app.currentImgData.currentXYZ;
            elseif contains(tag,'rl')
                tag = tag(3:end);
                flag = 'lesionReview'; % recon preview
                temp = app.rlImgData.currentXYZ;
            end

            switch tag
                case 'SliceX'
                    temp = [temp(1) coord(1)  coord(2)];

                case 'SliceY'
                    temp = [coord(1) temp(2) coord(2)];

                case 'SliceZ'
                    temp = [coord(1) coord(2) temp(3)];
            end

            plot_3D_volume(app,flag,temp)

        end

        function Imgscrollwheel(app, event, h)
            tag = h.Tag;
            delta = event.VerticalScrollCount;

            if contains(tag,'voe')
                tag = tag(4:end);
                flag = 'VOE'; % virtual object editor
                imgData = app.imgWithLesionData;
            elseif contains(tag,'rp')
                tag = tag(3:end);
                flag = 'reconPreview'; % recon preview
                imgData = app.currentImgData;
            elseif contains(tag,'rl')
                tag = tag(3:end);
                flag = 'lesionReview'; % lesion review
                imgData = app.rlImgData;
            else
                return
            end

            switch tag
                case 'SliceX'
                    i=1;
                case 'SliceY'
                    i=2;
                case 'SliceZ'
                    i=3;
            end

            temp = imgData.currentXYZ;
            temp(i) = max(1,min(imgData.maxXYZ(i), temp(i)+delta));

            plot_3D_volume(app, flag, temp)

        end

        function ConsoleDataList = getConsoleDataList_WA(app)
            ftpobj = app.ftpinfo.ftpobj;
            consoleDBPath = app.ftpinfo.datapath;
            localDB = app.RawImportArchiveEditField.Value;

            % Get into directory with all the data
            cd(ftpobj,consoleDBPath)

            % Grab all the folders
            data = dir(ftpobj);
            dateEnd = inf;
            switch app.DateRangeDropDown.Value
                case 'Today'
                    dateStart = floor(now);
                case 'Past Week'
                    dateStart = floor(now) - 7;
                case 'Past 2 Weeks'
                    dateStart = floor(now) - 14;
                case 'Past 3 Weeks'
                    dateStart = floor(now) - 21;
                case 'Past Month'
                    dateStart = floor(now) - 30;
                case 'All'
                    dateStart = 0;
            end

            n = size(data,1);

            d = uiprogressdlg(app.LesionSynthesisToolboxUIFigure,'Title','Querying Target Scanner',...
                'Message',['Patient 0 of ' num2str(n)]);

            ConsoleDataList = {};
            for i = 1:n

                d.Value = i/n;
                d.Message = ['Patient ' num2str(i) ' of ' num2str(n)];

                % Folder containing scans for patient
                curr = data(i).name;
                pat_folders = curr;

                % Get into patient DIR
                cd(ftpobj,[consoleDBPath '/'  curr]); % filesep cant be used cuz were making a dir for the clients database

                % get ALL patient studies
                patients = dir(ftpobj);

                nseries = num2str(length(patients));

                for j = 1:1 %length(patients) - changed to avoid duplicate entries in the study list

                    % TO DO - what happens with a patient with repeat studies????

                    curr_series = patients(j).name;
                    cd(ftpobj,[consoleDBPath '/'  curr '/' curr_series]);

                    % Get into first available study dir
                    studies = dir(ftpobj);
                    cd(ftpobj,[consoleDBPath '/'  curr '/' curr_series '/' studies(1).name]);

                    % Find the first available file --> move into temp dir
                    files = dir(ftpobj);
                    if files(1).datenum>=dateStart && files(1).datenum<dateEnd
                        temp_file = [consoleDBPath '/'  curr '/' curr_series '/' studies(1).name '/' files(1).name];
                        mkdir(localDB,'temp')
                        temp_path = mget(ftpobj,temp_file,[localDB '/' 'temp']);
                        temp_info = dicominfo(temp_path{:});

                        patientID = temp_info.PatientID;
                        if ~strcmpi(patientID,'DQA') && ~strcmpi(patientID,'ct qc')
                            if  isfield(temp_info.PatientName,'GivenName') && isfield(temp_info.PatientName,'FamilyName')
                                patientName = [temp_info.PatientName.FamilyName ', ' temp_info.PatientName.GivenName];
                            elseif isfield(temp_info.PatientName,'FamilyName')
                                patientName = temp_info.PatientName.FamilyName;
                            else
                                patientName = 'N/A';
                            end
                            ConsoleDataList = [ConsoleDataList; {pat_folders, patientID, patientName, nseries}];
                        end

                        % Delete temp
                        rmdir([localDB filesep 'temp'],"s")
                    end

                end
                %
                %                 for j = 1:length(patstudies)
                %                     curr_series = patstudies(j).name;
                %                     series(j).name =  curr_series;
                %                     cd(ftpobj,[consoleDB '/'  curr '/' series(j).name]);
                %
                %                 end

            end % End of searching through patient directories

        end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.appRootDir = fileparts(mfilename('fullpath'));
            if exist([app.appRootDir filesep 'AppStatus.mat'],'file')
                load([app.appRootDir filesep 'AppStatus.mat'], 'd');
            else
                d.appDataDir = 'C:\Lesion Synthesis Toolbox\Application Data';
                d.appNeverRun = true;
            end
            if d.appNeverRun
                if isequal(initializeApp(app),0)
                    close(app.LesionSynthesisToolboxUIFigure);
                    return
                end
            end
            app.appDataDir = d.appDataDir;

            while ~isValidAppDataDir(app)
                selection = uiconfirm(app.LesionSynthesisToolboxUIFigure,{'The application data directory could not be located at:', app.appDataDir},...
                    'Missing application data directory',...
                    'Icon','warning',...
                    'Options',{'Initialize new','Locate existing','Abort'},...
                    'DefaultOption',1,'CancelOption',3);
                switch selection
                    case 'Initialize new'
                        initializeApp(app);
                    case 'Locate existing'
                        baseDir = uigetdir(d.appDataDir,'Select application data directory');
                        if isequal(baseDir,0)
                            close(app.LesionSynthesisToolboxUIFigure);
                            return
                        else
                            app.appDataDir = [baseDir filesep 'Application Data'];
                            d.appDataDir = app.appDataDir;
                            d.appNeverRun = false;
                            save([app.appRootDir filesep 'AppStatus.mat'], 'd');
                        end
                    case 'Abort'
                        app.LesionSynthesisToolboxUIFigure.CloseRequestFcn;
                        return
                end
            end
            disp(['Data root directory for LST settings and data: ' app.appDataDir])

            % Explicitly state position and size
            app.LesionSynthesisToolboxUIFigure.Position = [100 100 1600 900];

            % Initialize User Settings
            app.user = '';
            app.defPETIntensityModes = app.PETIntensityModeDropDown.Items; % Get the default list of PET intesity options, to which refernce regions will be added.

            % Style Viewer Axes
            % DiscoveryVOE - (1) Select Patient Tab
            axis(app.SliceX,'off')
            axis(app.SliceY,'off')
            axis(app.SliceZ,'off')
            % DiscoveryVOE - (2) Define Disease Tab
            axis(app.ddSliceX,'off')
            axis(app.ddSliceY,'off')
            axis(app.ddSliceZ,'off')

            app.RawReconParamsTable.ColumnEditable = logical([0 1]);
            app.SimulationReconParamsTable.ColumnEditable = logical([0 1]);

            % Developer MODE TURN OFF
            %             app.LesionSynthesisToolboxUIFigure.Name = 'Lesion Synthesis Toolbox - DEVELOPER MODE';
            %             app.LesionSynthesisToolboxUIFigure.Position = [-1024 -26 950 900];

            if ~exist([app.appDataDir filesep 'UsersData.mat'],'file')
                initializeUsers(app)
            end

            drawnow;
            app.LesionSynthesisToolboxUIFigure.CurrentObject = app.UsernameEditField; %% TO DO: this doesn't work - initialize focus to username edit

            app.LSTServiceStatusCheckTimer = timer('TimerFcn',@refreshLSTProcessingServiceIndicatorStatus,...
                'ExecutionMode','FixedDelay',...
                'Period',1.0,'BusyMode','Drop',...
                'Name','LST Processing Service Status Check Timer',...
                'Tag','LST Processing Service Status Check Timer',...
                'UserData',app.ProcessingServerRunningLamp);
            start(app.LSTServiceStatusCheckTimer);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.LesionSynthesisToolboxUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {900, 900};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {61, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Selection change function: HeadTabGroup
        function HeadTabGroupSelectionChanged(app, event)
            selectedTab  = app.HeadTabGroup.SelectedTab;
            selectedSubTab = app.VOETabGroup.SelectedTab;

            if strcmp(app.user,'')
                app.HeadTabGroup.SelectedTab = app.LoginTab;
            elseif strcmp(selectedTab.Title,'Login') && ~strcmp(app.user,'')
                app.HeadTabGroup.SelectedTab = event.OldValue;
            elseif strcmp(selectedTab.Title,'DiscoveryDR')
                app.ScannerDropDown.Items = app.ScannerParamsTable.Data.Name;
            elseif strcmp(selectedTab.Title,'DiscoveryIR')
                updateDiscoveryIRTab(app)
            elseif strcmp(selectedTab.Title,'DiscoveryVOE') && strcmp(selectedSubTab.Title,'(1) - Select Patient')
                updateAvailRecons(app)
            end

        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)

            app.user = app.UsernameEditField.Value;

            load([app.appDataDir filesep 'UsersData.mat'],'usersData')
            i = find(strcmpi(usersData.AccountName, app.user));
            if isempty(i)
                app.MessageLampLabel.Text = 'Username does not exist';
                app.MessageLamp.Color = 'red';
                app.user = ''; % Failed Login Attempt
            else
                if strcmp(usersData.Password{i},app.PasswordEditField.Value)
                    app.project = usersData.Project{i};
                    app.MessageLampLabel.Text = app.user;
                    app.MessageLamp.Color = 'green'; % Sucessful Login Attempt
                    updateUsersTable(app,usersData);
                    sucessfulLogInFcn(app)
                else
                    app.MessageLampLabel.Text = 'Password does not match';
                    app.MessageLamp.Color = 'red';
                    app.user = ''; % Failed Login Attempt
                end
            end
        end

        % Value changed function: ScannerDropDown
        function ScannerDropDownValueChanged(app, event)
            value = app.ScannerDropDown.Value;

        end

        % Callback function
        function ScannersListBoxValueChanged(app, event)
            value = app.ScannersListBox.Value;

            app.ScannerNickname.Value = value;
            app.ScannerUsername.Value = app.scanners.(value).('Username');
            app.ScannerPassword.Value = app.scanners.(value).('Password');
            app.ScannerIPEditField.Value = app.scanners.(value).('IP');
            app.ScannerDataPath.Value = app.scanners.(value).('DataPath');
        end

        % Button pushed function: addScannerButton
        function addScannerButtonButtonPushed(app, event)
            name = 'New Scanner';
            done = false;
            while ~done
                name = inputdlg('Enter a new (unique) account name','New user account',1,{name});
                if isempty(name)
                    return
                end
                name = name{1};
                if any(strcmpi(name, app.ScannerParamsTable.Data.Name))
                    uialert(app.LesionSynthesisToolboxUIFigure,[name ' account already exist. Enter a unique account name.'],'Account name conflict');
                else
                    done = true;
                end
            end
            indx = size(app.ScannerParamsTable.Data,1)+1;
            app.ScannerParamsTable.Data.Name{indx} = name;
            app.ScannerParamsTable.Data.IP{indx} = '000.000.000.000';
            app.ScannerParamsTable.Data.Username{indx} = 'user';
            app.ScannerParamsTable.Data.Password{indx} = 'password';
            app.ScannerParamsTable.Data.DataPath{indx} = '/usr/g/sdc_image_pool/images/';
            ScannerParamsTableCellEdit(app,event);
        end

        % Button pushed function: deleteScannerButton
        function deleteScannerButtonButtonPushed(app, event)
            data = app.ScannerParamsTable.Data;
            selected = app.ScannerParamsTable.UserData;
            if isempty(selected) || selected(1)<1 || selected(1)>size(data,1)
                uialert(app.LesionSynthesisToolboxUIFigure,'No scanner selected in the scanner table.','Cannot delete scanner configuration');
            else
                data(selected(1),:) = [];
                app.ScannerParamsTable.Data = data;
                ScannerParamsTableCellEdit(app, event)
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)

            indx = strcmp(app.ScannerParamsTable.Data.Name, app.ScannerDropDown.Value);
            temp = app.ScannerParamsTable.Data(indx,:);

            app.ftpinfo.host = temp.IP{1};
            app.ftpinfo.user = temp.Username{1};
            app.ftpinfo.pass = temp.Password{1};
            app.ftpinfo.datapath = temp.DataPath{1};

            app.ConnectButton.BackgroundColor = 'Black';

            try
                app.ftpinfo.ftpobj = ftp(app.ftpinfo.host,app.ftpinfo.user,app.ftpinfo.pass);
                app.ConnectButton.BackgroundColor = 'Green';
                ConsoleDataList = getConsoleDataList_WA(app);
                app.rawImportTable.Data = ConsoleDataList;
                app.rawImportTable.ColumnFormat = {'char','char','char','char'};
                app.rawImportTable.ColumnWidth = {'auto', 'auto', 'auto','auto'};
            catch
                app.ConnectButton.BackgroundColor = 'Red';
            end
        end

        % Button pushed function: SelectStudyButton
        function SelectStudyButtonPushed(app, event)

            d = uiprogressdlg(app.LesionSynthesisToolboxUIFigure,'Message','Please wait while preparing Volume Explorer.','Indeterminate','on');

            % (1) Change to next tab
            app.VOETabGroup.SelectedTab = app.DefineDiseaseTab;

            % (2) Communicate Selected Patient
            clearSimulationTab(app)
            app.DVolumeViewerPanel.Title = strrep(app.ScanPreviewPanel.Title, 'Scan Preview: ', '3D Volume Viewer: ');
            plot_3D_volume(app,'VOE',[],app.currentImgData)
            ddColorLimitSliderValueChanging(app,app.spColorLimitSlider.Value);

            % (3) Define reconstruction parameters - Default settings
            count = load([app.appDataDir filesep 'numSims.mat'],'count');
            count = count.count + 1;
            save([app.appDataDir filesep 'numSims.mat'],'count');
            updateReconParamTable(app, app.SimulationReconParamsTable);

            close(d);

        end

        % Callback function
        function SliderValueChanging(app, event)
            changingValue = event.Value/100;

            set(app.AxesImgX,'clim',[0 app.MaxClims.x*changingValue])
            set(app.AxesImgY,'clim',[0 app.MaxClims.y*changingValue])
            set(app.AxesImgZ,'clim',[0 app.MaxClims.z*changingValue])

        end

        % Button pushed function: SaveProjectSettings
        function SaveProjectSettingsButtonPushed(app, event)
            labeling.simulationNameTemplate = app.SimulationNameTemplateEditField.Value;
            labeling.seriesDescription = app.SeriesDescriptionEditField.Value;
            displaySettings.locationUnits = app.ImageCoordinateUnitsDropDown.Value;
            displaySettings.imageUnits = app.ImageUnitsDropDown.Value;
            rawDataArchiveDir = app.RawImportArchiveEditField.Value;
            reconImageArchiveDir = app.ImageReconArchiveEditField.Value;
            simulationArchiveDir = app.SimulationArchiveEditField.Value;
            DICOMSend = struct('TargetHost',app.DICOMSendTargetHostNameEditField.Value,...
                'TargetPort',app.DICOMSendTargetPortEditField.Value,...
                'TargetAET',app.DICOMSendTargetAETEditField.Value,...
                'SourceAET',app.DICOMSendSourceAETEditField.Value,...
                'IncludeCT',app.DICOMSendIncludeCTCheckBox.Value);
            notification.Pushbullet.Tocken = app.PushbulletTockenEditField.Value;

            reconProtocols.directory = reconProfilesDir(app);
            reconProtocols.defaultProtocol = app.DefaultReconProtocolDropDown.Value;
            comments = app.ProjectCommentsTextArea.Value;

            dir = projectDir(app);
            if exist(dir,'dir') ~=7
                mkdir(dir);
            end
            save([dir filesep app.project '.mat'],'labeling','displaySettings','rawDataArchiveDir',...
                'reconImageArchiveDir','simulationArchiveDir', 'DICOMSend', 'reconProtocols', 'notification','comments');
        end

        % Callback function: DiscoveryVOETab, VOETabGroup
        function VOETabGroupSelectionChanged(app, event)
            selectedTab = app.VOETabGroup.SelectedTab;
            % Remove completed
            if strcmp(selectedTab.Title,'(3) - Job Queue')
                updateSimulationJobQueue(app);
            elseif strcmp(selectedTab.Title,'(4) - Proof Simulations')
                app.OpenfilelocationButton.UserData = app.SimulationArchiveEditField.Value;
                files = listfiles('*_fIR3D.mat',app.SimulationArchiveEditField.Value,'s');
                files(contains(files,[filesep 'Baseline_PET' filesep])) = [];
                nfolders = length(files);
                [patientID, res] = strtok(files,filesep);
                [simName, res] = strtok(res,filesep);
                simName = erase(simName, filesep);
                reconProfile = erase(res,'_fIR3D.mat');
                reconProfile = erase(reconProfile,filesep);
                seriesDesc = cell(nfolders,1);
                simDateTime = cell(nfolders,1);
                nObject = cell(nfolders,1);
                for i = 1:nfolders
                    temp = load([app.SimulationArchiveEditField.Value filesep patientID{i} filesep simName{i} filesep reconProfile{i} '_LesionParams.mat']);
                    seriesDesc{i} = temp.info.simParams.SeriesDesc;
                    if isfield(temp.info,'simDateTime')
                        simDateTime{i} = datestr(temp.info.simDateTime,31);
                    else
                        simDateTime{i} = '';
                    end
                    nObject{i} = length(temp.lesion);
                end
                app.AvailSimulationsTable.Data = [patientID,simName,reconProfile,seriesDesc,simDateTime,nObject];
            end

        end

        % Cell selection callback: AvailReconsTable
        function AvailReconsTableCellSelection(app, event)
            indices = event.Indices;
            app.AvailReconsTable.UserData.Indices = indices;

            app.currentPatientID = cell2mat(event.Source.Data(indices(1),1));
            currentReconName = cell2mat(event.Source.Data(indices(1),5));

            fpath = [app.ImageReconArchiveEditField.Value filesep, ...
                app.currentPatientID];

            if strcmpi(cell2mat(event.Source.Data(indices(1),5)),'fIR3D')
                imgFile = [fpath filesep currentReconName '.mat'];
            else
                imgFile = [fpath filesep currentReconName '_fIR3D.mat'];
            end
            h = uiprogressdlg(app.LesionSynthesisToolboxUIFigure,'Title','Loading reconstructed image',...
                'Message',['Loading PET ' imgFile], 'Indeterminate','on');
            imgData = load(imgFile, 'vol','hdr');
            imgData.vol = applyUptakeUnits(imgData.vol, imgData.hdr, app.ImageUnitsDropDown.Value);
            h.Message = ['Loading CT for ' imgFile];
            imgData = addCTvol(app, imgData, fpath);
            plot_3D_volume(app,'reconPreview',[],imgData)
            app.ScanPreviewPanel.Title = ['Scan Preview: ' strjoin(event.Source.Data(indices(1),:) ,' | ')];
            close(h);

        end

        % Value changed function: ObjectLocationEditField
        function ObjectLocationEditFieldValueChanged(app, event)
            value = str2num(app.ObjectLocationEditField.Value);
            % x and y locations are transposed on purpose due to image reorientation for display
            value = value([2 1 3]);
            app.currentXYZ = value;
            plot_3D_volume(app,'VOE',value);
        end

        % Button pushed function: AddObjectButton
        function AddObjectButtonPushed(app, event)
            if isempty(app.SimulationObjectsListBox.Items)
                count = 1;
            else
                count = length(app.SimulationObjectsListBox.Items) + 1;
            end

            locUnits = 'mm from center';
            app.lesionInfo{count}.name = app.ObjectNameEditField.Value;
            app.lesionInfo{count}.loc = convertImgLocationUnits(app.currentImgData.hdr, str2num(app.ObjectLocationEditField.Value), app.locationDisplayUnits, locUnits);
            app.lesionInfo{count}.locUnits = locUnits;
            app.lesionInfo{count}.shape = app.ShapeDropDown.Value;
            app.lesionInfo{count}.rad = app.RadiusmmEditField.Value;
            app.lesionInfo{count}.PTval = app.PETIntensityEditField.Value;
            app.lesionInfo{count}.PTadditionMode = app.PETIntensityAddModeDropDown.Value;
            app.lesionInfo{count}.CTval = app.CTIntensityHUEditField.Value;
            app.lesionInfo{count}.CTadditionMode = app.CTIntensityAddModeDropDown.Value;
            app.lesionInfo{count}.mode = app.PETIntensityModeDropDown.Value;
            app.lesionInfo{count}.hdr = app.currentImgData.hdr;

            if isnan(app.VarianceSlider.Value)
                app.lesionInfo{count}.var = 0;
            else
                app.lesionInfo{count}.var = app.VarianceSlider.Value;
            end

            items = cell(count,1);
            for i = 1:count
                items{i} = app.lesionInfo{i}.name;
            end

            app.SimulationObjectsListBox.Items = items;

            % Set up for next object
            app.ObjectNameEditField.Value = ['Object_' num2str(length(app.SimulationObjectsListBox.Items)+1)];

            updateLesionVol(app);
        end

        % Button pushed function: RemoveObjectButton
        function RemoveObjectButtonPushed(app, event)
            i = app.SimulationObjectsListBox.Value;
            if ~isempty(i)
                app.SimulationObjectsListBox.Items(i) = [];
                app.lesionInfo(i) = [];
                updateLesionVol(app);
            else
                beep;
            end
        end

        % Button pushed function: QueueSimButton
        function QueueSimButtonPushed(app, event)
            %Update Simulation Queue

            refROI = app.refROIInfo;
            info.simParams = app.SimulationReconParamsTable.Data;
            info.simParams = cell2struct(info.simParams(:,2)',info.simParams(:,1)',2);
            info.simParams.bedRange = app.BedRangeDropDown.Value;
            lesion = app.lesionInfo;
            % TO DO - look for where there is appropriate baseline data that
            % has been reconstructed already
            info.patDataDir = [app.SimulationArchiveEditField.Value filesep app.currentPatientID filesep 'Baseline_PET'];
            if exist(info.patDataDir,'dir')~=7
                info.patDataDir = [app.ImageReconArchiveEditField.Value filesep app.currentPatientID];
            end
            info.simulationDir = app.SimulationQueueEditField.Value;
            info.simulationArchiveDir = [app.SimulationArchiveEditField.Value filesep app.currentPatientID];
            info.DICOMSend = struct('TargetHost',app.DICOMSendTargetHostNameEditField.Value,...
                'TargetPort',app.DICOMSendTargetPortEditField.Value,...
                'TargetAET',app.DICOMSendTargetAETEditField.Value,...
                'SourceAET',app.DICOMSendSourceAETEditField.Value,...
                'IncludeCT',app.DICOMSendIncludeCTCheckBox.Value);
            info.patientID = app.currentPatientID; %TO DO - change currentName to currentPatientID
            info.reconName  = info.simParams.SimName;
            info.reconProfile = app.SimulationReconProfileDropDown.Value;
            info.simDateTime = datestr(now);

            dir = [info.simulationDir filesep app.currentPatientID ' - ' info.reconName];
            if exist(dir,'dir')==7
                uialert(app.LesionSynthesisToolboxUIFigure,'A simulation for this name is already queued for this patient. This will cause chaos and confusion. Please rename the simulations (SimName).','Simulation name conflict.')
                return;
            end
            mkdir(dir)
            save([dir filesep info.reconProfile '_LesionParams.mat'],'info','lesion','refROI')

            if app.KeeplesionsCheckBox.Value == 0
                clearSimulationTab(app)
            end

            app.VOETabGroup.SelectedTab = app.SelectPatientTab;
        end

        % Callback function
        function SimulateStudiesButtonValueChanged(app, event)
            if 0 % Run within the same Matlab session
                runSimulationServer('start', app.SimulationQueueEditField.Value, app.PushbulletTockenEditField.Value)
            else % Start a seperate service
                cmd = ['matlab -nodesktop -nosplash -r "runSimulationServer(''start'', ''' app.SimulationQueueEditField.Value ''', ''' app.PushbulletTockenEditField.Value ''')" &'];
                system(cmd)
            end
        end

        % Cell selection callback: rawImportTable
        function rawImportTableCellSelection(app, event)
            indices = event.Indices;
            data = app.rawImportTable.Data;
            val = indices(1,1);

            searchDir = data{val,1};

            ftpobj = app.ftpinfo.ftpobj;
            consoleDBPath = app.ftpinfo.datapath;
            localDB = app.RawImportArchiveEditField.Value;

            % Get into directory with all the data
            cd(ftpobj,[consoleDBPath '/' searchDir]);
            folders = dir(ftpobj);

            for i = 1:length(folders)
                cd(ftpobj,[consoleDBPath '/' searchDir '/' folders(i).name])
                subFolders = dir(ftpobj);

                import{i} = 0;
                fDir{i} = [searchDir '/' folders(i).name];
                SD{i} = 'None';
                AD{i} = 'NA';
                AT{i} = 'NA';

                temp={};
                for si = 1:length(subFolders)
                    cd(ftpobj,[consoleDBPath '/' searchDir '/' folders(i).name '/' subFolders(si).name])
                    files = dir(ftpobj);

                    temp_file =[consoleDBPath '/' searchDir '/' folders(i).name '/' subFolders(si).name '/' files(1).name];
                    if ~exist([localDB filesep 'temp'],'dir')
                        mkdir(localDB,'temp')
                    end
                    temp_path = mget(ftpobj,temp_file,[localDB '/' 'temp']);
                    temp = dicominfo(temp_path{:});

                    if strcmpi(temp.Modality,'GEMS PET RAW')
                        import{i} = 0;
                        fDir{i} = [searchDir '/' folders(i).name];
                        SD{i} = temp.SeriesDescription;
                        AD{i} = temp.AcquisitionDate;
                        AT{i} = temp.AcquisitionTime;
                        break
                    end
                end

            end

            seriesData = [import', fDir',SD',AD',AT'];
            app.seriesImportTable.Data = seriesData;
            app.seriesImportTable.ColumnFormat = {'logical','char','char','char','char'};
            app.seriesImportTable.ColumnWidth = {'auto','auto', 'auto', 'auto','auto'};
            app.seriesImportTable.ColumnEditable = logical([1 0 0 0 0]);

        end

        % Cell edit callback: seriesImportTable
        function seriesImportTableCellEdit(app, event)
            app.seriesImportTable.Data{event.Indices(1),event.Indices(2)} = event.EditData;
        end

        % Display data changed function: seriesImportTable
        function seriesImportTableDisplayDataChanged(app, event)
            newDisplayData = app.seriesImportTable.DisplayData;

        end

        % Button pushed function: ImportSelectedStudiesButton
        function ImportSelectedStudiesButtonPushed(app, event)
            if exist(app.RawImportArchiveEditField.Value,'dir')
                currStudyData = app.seriesImportTable.Data;
                for i = 1:size(currStudyData,1)
                    if ischar(currStudyData{i,1})
                        currStudyData{i,1} = str2num(currStudyData{i,1});
                    end
                end
                if ~any(cell2mat(currStudyData(:,1)))
                    uialert(app.LesionSynthesisToolboxUIFigure, 'No series have been selected for download','Import Raw Data Error');
                    return
                end

                d = uiprogressdlg(app.LesionSynthesisToolboxUIFigure,'Title','Import Status',...
                    'Message','Importing Selected Studies', 'Indeterminate','on');
                ftpobj = app.ftpinfo.ftpobj;
                app.consoleDB = app.ftpinfo.datapath;

                for i = 1:size(currStudyData,1)
                    getStudy = currStudyData{i,1};
                    if getStudy
                        studyPath = currStudyData{i,2};
                        name = erase(studyPath,'/'); % Consider adding MRN to name (???)
                        cd(ftpobj,[app.consoleDB '/' studyPath]);
                        series = dir(ftpobj);
                        hasDiagnosticCT = false;
                        mkdir(app.RawImportArchiveEditField.Value,name);
                        % First Search for RPDC files
                        for j = 1:size(series,1)
                            cd(ftpobj,[app.consoleDB '/' studyPath '/' series(j).name]);
                            seriesFiles = dir(ftpobj);
                            if strfind(seriesFiles(1).name,'RPDC')
                                mkdir([app.RawImportArchiveEditField.Value filesep name],'raw');
                                for k = 1:size(seriesFiles,1)
                                    % get the raw file header
                                    d.Message = 'Transfering RPDC file';
                                    t = mget(ftpobj,seriesFiles(k).name,[app.RawImportArchiveEditField.Value filesep name filesep 'raw']);
                                    info = dicominfo(t{:});
                                    if isfield(info,'Private_0009_1062')
                                        d.Message = 'Transfering associated sinogram for RPDC file';
                                        fsino = info.Private_0009_1062;
                                        [p,f,~] = fileparts(fsino);
                                        cd(ftpobj,p);
                                        % get the raw file sinogram
                                        mget(ftpobj,f,[app.RawImportArchiveEditField.Value filesep name filesep 'raw']);
                                        cd(ftpobj,[app.consoleDB '/' studyPath '/' series(j).name]); % Back to series Dir
                                    elseif isfield(info,'Private_0019_1007')
                                        d.Message = 'Transfering well counter file';
                                        % well counter file
                                        % do nothing - REGRECON can process this file
                                    elseif isfield(info,'Private_0017_1005')
                                        % 3D geometric file OR
                                        % Normalization file
                                        if strfind(info.Private_0017_1005,'3D Geometric Calibration')
                                            type = 'geo3d';
                                            d.Message = 'Transfering geometric calibration file';
                                        elseif strfind(info.Private_0017_1005,'PET 3D Normalization')
                                            type = 'norm3d';
                                            d.Message = 'Transfering normalization file';
                                        end
                                        file = info.Private_0017_1007;
                                        [p,f,~] = fileparts(file);
                                        cd(ftpobj,p);
                                        t = mget(ftpobj,f,[app.RawImportArchiveEditField.Value filesep name filesep 'raw']);
                                        movefile(t{:},[app.RawImportArchiveEditField.Value filesep name filesep type]);
                                        cd(ftpobj,[app.consoleDB '/' studyPath '/' series(j).name]); % Back to series Dir
                                    end

                                end
                                nRPDC = size(seriesFiles,1) - 3; % Wellcounter (WC) + GEO + NORM
                                d.Message = 'Completed All RPDC files';
                            end

                            if strfind(seriesFiles(1).name,'CTDC')
                                d.Message = 'Searching for CTDC files';
                                ftemp = mget(ftpobj,seriesFiles(1).name);
                                info = dicominfo(ftemp{:});
                                if contains(info.SeriesDescription,'CTAC') && ~hasDiagnosticCT
                                    mkdir([app.RawImportArchiveEditField.Value filesep name],'CTAC');
                                    d.Message = 'Transfering CTDC files';
                                    for k = 1:size(seriesFiles,1)
                                        mget(ftpobj,seriesFiles(k).name,[app.RawImportArchiveEditField.Value filesep name filesep 'CTAC']);
                                    end
                                elseif contains(info.SeriesDescription,'WB Standard') || contains(info.SeriesDescription,'CT STANDARD 2.5')
                                    if hasDiagnosticCT
                                        warning('Multiple CTs (in addition to CTAC) encountered for this study')
                                    end
                                    hasDiagnosticCT = true;
                                    if exist([app.RawImportArchiveEditField.Value filesep name filesep 'CTAC'],'dir')==7
                                        disp('Removing CTAC files since there is a diagnostic CT') % TO DO - can be done better
                                        delete([app.RawImportArchiveEditField.Value filesep name filesep 'CTAC' filesep '*.*'])
                                    else
                                        mkdir([app.RawImportArchiveEditField.Value filesep name], 'CTAC');
                                    end
                                    d.Message = 'Transfering CT files';
                                    for k = 1:size(seriesFiles,1)
                                        mget(ftpobj,seriesFiles(k).name,[app.RawImportArchiveEditField.Value filesep name filesep 'CTAC']);
                                    end
                                end
                                delete(ftemp{:})
                            end

                        end

                    end
                end

                close(d)

                if app.AnonymizeImportedStudiesCheckBox.Value
                    AnonymizeRawDataButtonPushed(app, [app.RawImportArchiveEditField.Value filesep name])
                end

            else
                uialert(app.LesionSynthesisToolboxUIFigure,[ ' Review Configure Tab ' newline 'Error locating Raw Import Archieve at:' newline app.RawImportArchiveEditField.Value  ],'Import Raw Data Error');
            end
        end

        % Cell selection callback: rawArchiveTable
        function rawArchiveTableCellSelection(app, event)
            if nargin>=2
                indices = event.Indices;
                app.rawArchiveTable.UserData.CurrentCell = indices;
            end
            params.rawDataSource = app.rawArchiveTable.Data{app.rawArchiveTable.UserData.CurrentCell(1,1),1};
            updateReconParamTable(app, app.RawReconParamsTable, app.RawReconProfilesDropDown.Value, params);

        end

        % Value changed function: StartProcessingServiceButton, 
        % ...and 1 other component
        function InitiateProcessingService(app, event)
            if 0
                LSTProcessingService('start',{app.ImageReconQueueEditField.Value,app.SimulationQueueEditField.Value})
            else
                cmd = ['matlab -nodesktop -nosplash -r "LSTProcessingService(''start'', { ''' app.ImageReconQueueEditField.Value ''', ''' app.SimulationQueueEditField.Value '''})" &'];
                system(cmd)
            end

        end

        % Button pushed function: QueueImageReconstructionButton
        function QueueImageReconstructionButtonPushed(app, event)
            indices = app.rawArchiveTable.UserData.CurrentCell;
            patientID = app.rawArchiveTable.Data{indices(1),2};

            info.reconParams = app.RawReconParamsTable.Data;
            info.reconParams = cell2struct(info.reconParams(:,2)',info.reconParams(:,1)',2);

            info.saveDir = app.ImageReconArchiveEditField.Value;
            info.reconName = info.reconParams.SimName;
            info.patDataDir = [app.RawImportArchiveEditField.Value filesep info.reconParams.rawDataSource];

            if ~exist(app.ImageReconQueueEditField.Value,'dir')
                mkdir(app.ImageReconQueueEditField.Value)
            end
            if ~exist([app.ImageReconQueueEditField.Value filesep patientID],'dir')
                mkdir(app.ImageReconQueueEditField.Value, patientID)
            end

            save([app.ImageReconQueueEditField.Value filesep patientID filesep info.reconName '_reconParams.mat'],'info')

            count = load([app.appDataDir filesep 'numSims.mat'],'count');
            count = count.count + 1;
            save([app.appDataDir filesep 'numSims.mat'],'count');

            files = listfiles('*_reconParams.mat', app.ImageReconQueueEditField.Value,'s');
            app.ImageReconstructionJobQueueListBox.Items = files';
        end

        % Button pushed function: RemoveJobButton
        function RemoveJobButtonPushed(app, event)
            if ~isempty(app.ImageReconstructionJobQueueListBox.Items)
                sel = app.ImageReconstructionJobQueueListBox.Value;
                delete([app.ImageReconQueueEditField.Value filesep sel]);
                dir = [app.ImageReconQueueEditField.Value filesep fileparts(sel)];
                files = listfiles('*_reconParams.mat', dir);
                if isempty(files) % remove the patient directory if there are no other reconstruction queued
                    status = rmdir(dir,'s');
                end
                files = listfiles('*_reconParams.mat', app.ImageReconQueueEditField.Value,'s');
                app.ImageReconstructionJobQueueListBox.Items = files';
            end
        end

        % Value changing function: ddColorLimitSlider
        function ddColorLimitSliderValueChanging(app, event)
            plot_3D_volume(app,'VOE');
        end

        % Value changing function: spColorLimitSlider
        function spColorLimitSliderValueChanging(app, event)
            plot_3D_volume(app,'reconPreview');
        end

        % Button pushed function: RemoveSimJobBtn
        function RemoveSimJobBtnButtonPushed(app, event)
            index = app.RemoveSimJobBtn.UserData;
            if ~isempty(index) && index <= size(app.SimsInQueueTable.Data,1)
                sel = app.SimsInQueueTable.Data{index,1};
                if exist([app.SimulationQueueEditField.Value filesep sel],'dir')
                    rmdir([app.SimulationQueueEditField.Value filesep  sel],'s')
                end
                data = app.SimsInQueueTable.Data;
                data(index,:) = [];
                app.SimsInQueueTable.Data = data;
                app.RemoveSimJobBtn.UserData = [];
            end
        end

        % Value changed function: PasswordEditField
        function PasswordEditFieldValueChanged(app, event)
            LoginButtonPushed(app, event)
        end

        % Cell selection callback: SimsInQueueTable
        function SimsInQueueTableCellSelection(app, event)
            indices = event.Indices;
            app.RemoveSimJobBtn.UserData = indices(1);
            app.RemoveSimJobBtn.Enable = 'on';
        end

        % Window scroll wheel function: LesionSynthesisToolboxUIFigure
        function LesionSynthesisToolboxUIFigureWindowScrollWheel(app, event)
            % 			h = overobj('image');
            % h = overobj2('type','axes')
            h = hittest(app.LesionSynthesisToolboxUIFigure);
            try
                h = findobj(h,'Type','Image');
                if ~isempty(h)
                    Imgscrollwheel(app, event, h);
                end
            catch anonerr
            end
        end

        % Button pushed function: AddRefObjectButton
        function AddRefObjectButtonPushed(app, event)
            %% TO DO check that reference and lesion object names are unique
            if isempty(app.RefObjectsListBox.Items)
                count = 0;
            else
                count = length(app.RefObjectsListBox.Items);
                names = cell(count,1);
                for i=1:count
                    names{i} = app.refROIInfo{i}.name;
                end
                if any(strcmpi(names, app.RefObjectNameEditField.Value))
                    uialert(app.LesionSynthesisToolboxUIFigure,...
                        {['A reference ROI object with the name ' app.RefObjectNameEditField.Value ' already exists.'],...
                        'Please rename the object with a unique name and then add it to the list.'},...
                        'Reference ROI name conflict');
                    return;
                end
            end

            locUnits = 'mm from center';
            loc = convertImgLocationUnits(app.imgWithLesionData.hdr, str2num(app.RefObjectLocationEditField.Value), app.locationDisplayUnits, locUnits);
            shape = app.RefShapeDropDown.Value;
            rad = app.RefRadiusmmEditField.Value;

            count = count+1;
            app.refROIInfo{count}.name = app.RefObjectNameEditField.Value;
            app.refROIInfo{count}.loc = loc;
            app.refROIInfo{count}.locUnits = locUnits;
            app.refROIInfo{count}.shape = shape;
            app.refROIInfo{count}.rad = rad;
            app.refROIInfo{count}.hdr = app.imgWithLesionData.hdr;

            for i = 1:count
                items{i} = app.refROIInfo{i}.name;
            end

            app.RefObjectsListBox.Items = items;

            % Update Lesion intensity mode options
            app.PETIntensityModeDropDown.Items = [app.defPETIntensityModes, strcat('Lesion:',items)];
            app.PETIntensityModeDropDown.Value = strcat('Lesion:',app.RefObjectNameEditField.Value);
            for i = 1:3
                app.PETIntensityModeDropDown.Visible = 'off';
                pause(0.1);
                app.PETIntensityModeDropDown.Visible = 'on';
                pause(0.1);
            end
            % Set up next reference object
            app.RefObjectNameEditField.Value = ['Ref_' num2str(count+1)];
        end

        % Button pushed function: RemoveRefObjectButton
        function RemoveRefObjectButtonPushed(app, event)
            i = strcmp(app.RefObjectsListBox.Items, app.RefObjectsListBox.Value);
            app.RefObjectsListBox.Items(i) = [];
            app.refROIInfo(i) = [];
            app.PETIntensityModeDropDown.Items = [app.defPETIntensityModes, strcat('Lesion:',app.RefObjectsListBox.Items)];
        end

        % Value changed function: SimulationReconProfileDropDown
        function SimulationReconProfileDropDownValueChanged(app, event)
            value = app.SimulationReconProfileDropDown.Value;
            updateReconParamTable(app, app.SimulationReconParamsTable, value)
        end

        % Button pushed function: RawImportBrowseButton
        function RawImportBrowseButtonPushed(app, event)
            value = app.RawImportArchiveEditField.Value;
            value = uigetdir(value,'Select directory to import raw data');
            if ~isnumeric(value)
                app.RawImportArchiveEditField.Value = value;
                RawImportArchiveEditFieldValueChanged(app);
            end
        end

        % Button pushed function: ImageReconArchiveBrowseButton
        function ImageReconArchiveBrowseButtonPushed(app, event)
            value = app.ImageReconArchiveEditField.Value;
            value = uigetdir(value,'Select directory to reconstruct raw images');
            if ~isnumeric(value)
                app.ImageReconArchiveEditField.Value = value;
                ImageReconArchiveEditFieldValueChanged(app);
            end
        end

        % Button pushed function: SimulationQueueBrowseButton
        function SimulationQueueBrowseButtonPushed(app, event)
            value = app.SimulationQueueEditField.Value;
            value = uigetdir(value,'Select temporary directory to simulate lesions');
            if ~isnumeric(value)
                app.SimulationQueueEditField.Value = value;
                SimulationQueueEditFieldValueChanged(app);
            end
        end

        % Button pushed function: SimulationArchiveBrowseButton
        function SimulationArchiveBrowseButtonPushed(app, event)
            value = app.SimulationArchiveEditField.Value;
            value = uigetdir(value,'Select directory to archive images with lesions');
            if ~isnumeric(value)
                app.SimulationArchiveEditField.Value = value;
                SimulationArchiveEditFieldValueChanged(app);
            end
        end

        % Button pushed function: ImageReconQueueBrowseButton
        function ImageReconQueueBrowseButtonPushed(app, event)
            value = app.ImageReconQueueEditField.Value;
            value = uigetdir(value,'Select directory to archive images with lesions');
            if ~isnumeric(value)
                app.ImageReconQueueEditField.Value = value;
                ImageReconQueueEditFieldValueChanged(app);
            end
        end

        % Value changed function: RawImportArchiveEditField
        function RawImportArchiveEditFieldValueChanged(app, event)
            value = app.RawImportArchiveEditField.Value;

        end

        % Value changed function: ImageReconArchiveEditField
        function ImageReconArchiveEditFieldValueChanged(app, event)
            value = app.ImageReconArchiveEditField.Value;

        end

        % Value changed function: SimulationQueueEditField
        function SimulationQueueEditFieldValueChanged(app, event)
            value = app.SimulationQueueEditField.Value;

        end

        % Value changed function: SimulationArchiveEditField
        function SimulationArchiveEditFieldValueChanged(app, event)
            value = app.SimulationArchiveEditField.Value;

        end

        % Value changed function: ImageReconQueueEditField
        function ImageReconQueueEditFieldValueChanged(app, event)
            value = app.ImageReconQueueEditField.Value;
            app.ImageReconQueueEditField.Value = value;
        end

        % Callback function
        function AvailSimulationsTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

        end

        % Cell selection callback: AvailSimulationsTable
        function AvailSimulationsTableCellSelection(app, event)
            if nargin>=2
                indices = event.Indices;
                app.AvailSimulationsTable.UserData.Indices = indices;
            else
                indices = app.AvailSimulationsTable.UserData.Indices;
            end

            d = uiprogressdlg(app.LesionSynthesisToolboxUIFigure,'Title','Loading lesion study',...
                'Message',['Loading lesion synthesis data for ' strjoin(app.AvailSimulationsTable.Data(indices(1),1:4), ' | ') ], 'Indeterminate','on');

            patientID = app.AvailSimulationsTable.Data{indices(1),1};

            app.currentPatientID = patientID;
            currentReconName = app.AvailSimulationsTable.Data{indices(1),3};
            fpath = [app.ImageReconArchiveEditField.Value filesep, ...
                app.currentPatientID];
            if strcmpi(cell2mat(app.AvailSimulationsTable.Data(indices(1),3)),'fIR3D')
                imgFile = [fpath filesep currentReconName '.mat'];
            else
                imgFile = [fpath filesep currentReconName '_fIR3D.mat'];
            end
            imgData = load(imgFile, 'vol','hdr');
            imgData.vol = applyUptakeUnits(imgData.vol, imgData.hdr, app.ImageUnitsDropDown.Value);
            imgData = addCTvol(app, imgData, fpath);
            app.currentImgData = imgData;

            simName = app.AvailSimulationsTable.Data{indices(1),2};
            reconProfile = app.AvailSimulationsTable.Data{indices(1),3};
            dir = [app.SimulationArchiveEditField.Value filesep patientID filesep simName];

            app.OpenfilelocationButton.UserData = dir;
            lesionData = load([dir filesep reconProfile '_LesionParams.mat']);
            lesionImgData = load([dir filesep reconProfile '_fIR3D.mat'], 'vol','hdr');
            lesionImgData.vol = applyUptakeUnits(lesionImgData.vol, lesionImgData.hdr, app.ImageUnitsDropDown.Value);
            if exist([dir filesep 'CTAC.mat'],'file') % use the CT with lesions
                lesionImgData = addCTvol(app, lesionImgData, dir);
            else % use original CT
                lesionImgData.CTvol = imgData.CTvol; % equivalent to but faster: lesionImgData = addCTvol(app, lesionImgData, fpath);
            end

            if isfield(lesionData,'refROI')
                rData = cell(length(lesionData.refROI),4);
                for i = 1:length(lesionData.refROI)
                    params = getShapeParamStr(lesionData.refROI{i});
                    if ~isfield(lesionData.refROI{i},'locUnits')
                        lesionData.refROI{i}.locUnits = 'pixel';
                    end
                    if strcmp(app.locationDisplayUnits,'relative fraction')
                        locStr = mynum2str(convertImgLocationUnits(lesionImgData.hdr, lesionData.refROI{i}.loc, lesionData.refROI{i}.locUnits, app.locationDisplayUnits),3,3);
                    else
                        locStr = mynum2str(convertImgLocationUnits(lesionImgData.hdr, lesionData.refROI{i}.loc, lesionData.refROI{i}.locUnits, app.locationDisplayUnits),3,0);
                    end
                    rData(i,:) = {lesionData.refROI{i}.name,...
                        locStr,...
                        lesionData.refROI{i}.shape,...
                        params};
                end
            else
                rData = {};
            end
            app.RefROITable.Data = rData;

            lData = cell(length(lesionData.lesion),8);
            for i = 1:length(lesionData.lesion)
                params = getShapeParamStr(lesionData.lesion{i});
                if ~isfield(lesionData.lesion{i},'locUnits')
                    lesionData.lesion{i}.locUnits = 'pixel';
                end
                if strcmp(app.locationDisplayUnits,'relative fraction')
                    locStr = mynum2str(convertImgLocationUnits(lesionImgData.hdr, lesionData.lesion{i}.loc, lesionData.lesion{i}.locUnits, app.locationDisplayUnits),3,3);
                else
                    locStr = mynum2str(convertImgLocationUnits(lesionImgData.hdr, lesionData.lesion{i}.loc, lesionData.lesion{i}.locUnits, app.locationDisplayUnits),3,0);
                end
                lData(i,:) = {lesionData.lesion{i}.name,...
                    locStr,...
                    lesionData.lesion{i}.mode,...
                    lesionData.lesion{i}.PTval,...
                    lesionData.lesion{i}.CTval,...
                    lesionData.lesion{i}.shape,...
                    lesionData.lesion{i}.PTadditionMode,...
                    params};

            end
            app.LesionTable.Data = lData;
            app.SimulationReviewPanel.Title = ['Simulation Review: ' strjoin(app.AvailSimulationsTable.Data(indices(1),1:4), ' | ')];
            plot_3D_volume(app,'lesionReview',[],lesionImgData)

            close(d);

        end

        % Cell selection callback: LesionTable
        function LesionTableCellSelection(app, event)
            indices = event.Indices;
            col = strcmpi(event.Source.ColumnName,'Location');
            value = str2num(event.Source.Data{indices(1), col});
            % Change order between x and y to account for image storage
            % convention.
            value = value([2 1 3]);
            loc = convertImgLocationUnits(app.rlImgData.hdr , value, app.locationDisplayUnits, 'pixel');
            plot_3D_volume(app,'lesionReview',loc)
        end

        % Cell selection callback: RefROITable
        function RefROITableCellSelection(app, event)
            LesionTableCellSelection(app, event)
        end

        % Callback function
        function rlColorLimitSliderValueChanged(app, event)
            plot_3D_volume(app,'lesionReview')

        end

        % Value changing function: rlColorLimitSlider
        function rlColorLimitSliderValueChanging(app, event)
            plot_3D_volume(app,'lesionReview')

        end

        % Button pushed function: OpenfilelocationButton
        function OpenfilelocationButtonPushed(app, event)
            dir = app.OpenfilelocationButton.UserData;
            system(['explorer ' dir ' &']);
        end

        % Value changed function: PushbulletTockenEditField
        function PushbulletTockenEditFieldValueChanged(app, event)
            value = app.PushbulletTockenEditField.Value;
        end

        % Button pushed function: TestPushbulletButton
        function TestPushbulletButtonPushed(app, event)
            pushbulletH = Pushbullet(app.PushbulletTockenEditField.Value);
            pushbulletH.pushNote([],'Lesion Synthesis Toolbox Test Message',['This test message was sent by the Lesion Synthesis Toolbox on ' datestr(now)]);
            delete(pushbulletH);
        end

        % Button pushed function: GotoPushbulletwebsiteButton
        function GotoPushbulletwebsiteButtonPushed(app, event)
            dos('Start https://www.pushbullet.com/')
        end

        % Callback function
        function saveNotificationSettingsButtonPushed(app, event)
            Pushbullet.Tocken = app.PushbulletTockenEditField.Value;
            save([app.appDataDir filesep 'NotificationSettings.mat'],'Pushbullet');
        end

        % Value changed function: RawReconProfilesDropDown
        function RawReconProfilesDropDownValueChanged(app, event)
            rawArchiveTableCellSelection(app)
        end

        % Button pushed function: SaveGeneralSettings
        function SaveGeneralSettingsButtonPushed(app, event)
            saveUserData(app);

            reconQueueDir = app.ImageReconQueueEditField.Value;
            simulationQueueDir = app.SimulationQueueEditField.Value;
            scannerParams = app.ScannerParamsTable.Data;
            save([app.appDataDir filesep 'GeneralSettings.mat'],'scannerParams','reconQueueDir','simulationQueueDir');

        end

        % Button pushed function: addUserButton
        function addUserButtonPushed(app, event)
            name = '';
            done = false;
            while ~done
                name = inputdlg('Enter a new (unique) account name','New user account',1,{name});
                if isempty(name)
                    return
                end
                name = name{1};
                if any(strcmpi(name, app.UserAccountsTable.Data{:,1}))
                    uialert(app.LesionSynthesisToolboxUIFigure,[name ' account already exist. Enter a unique account name.'],'Account name conflict');
                else
                    done = true;
                end
            end

            userDir = [app.appDataDir filesep 'User Project Settings'];
            items = listfiles('*.mat',userDir,'s');
            itemsFormatted = strrep(items, filesep, ' - ');
            itemsFormatted = strrep(itemsFormatted, '.mat', '');

            [indx, tf] = uilistdlg('Default Project',...
                ['Select an existing project to duplicate as default for ' name],...
                itemsFormatted,...
                'SelectionMode','single',...
                'InitialValue','Admin - New Project',...
                'Options',{'Select','Create default'});
            if tf
                indx = find(strcmpi(itemsFormatted, indx));
                if ~exist([userDir filesep name],'dir')
                    mkdir(userDir, name)
                end
                copyfile([userDir filesep items{indx}], [userDir filesep name]);
                projectName = items{indx}(find(items{indx}==filesep,1)+1:end-4);
            else
                if 1
                    return
                else
                    % Create a default project file
                    load([app.appDataDir filesep 'DataDirectories.mat'], 'rawDataArchiveDir','reconImageArchiveDir','simulationArchiveDir','reconQueueDir','simulationQueueDir');
                    comments = {['Default project, generated during creation of new user ' name '.'],['Created on: ' datestr(now,31)]};
                    displaySettings = struct('locationUnits','pixel',...
                        'imageUnits','SUV-bw [g/mL]');
                    labeling = struct('simulationNameTemplate','LST %Count%',...
                        'seriesDescription','MAC LST');
                    notification.Pushbullet.Tocken = '';
                    reconProtocols = struct('directory', [app.appDataDir filesep 'Reconstruction Protocols'],...
                        'defaultProtocol','Default OSEM');
                    save([app.appDataDir filesep 'User Project Settings' filesep name filesep 'New Project.mat'],'comments','displaySettings','labeling','notification','rawDataArchiveDir','reconImageArchiveDir','simulationArchiveDir','reconProtocols');
                end
            end
            data = app.UserAccountsTable.Data;
            app.UserAccountsTable.Data = [data; {name, name, 'password', false, projectName}];
            saveUserData(app);

        end

        % Button pushed function: deleteUserButton
        function deleteUserButtonPushed(app, event)
            data = app.UserAccountsTable.Data;
            if size(data,1)<2
                uialert(app.LesionSynthesisToolboxUIFigure,'At least one user account must be defined.','Cannot delete user account');
            else
                selected = app.UserAccountsTable.UserData;
                if isempty(selected) || selected(1)<1 || selected(1)>size(data,1)
                    uialert(app.LesionSynthesisToolboxUIFigure,'No account selected in the user account table.','Cannot delete user account');
                end
                data(selected(1),:) = [];
                app.UserAccountsTable.Data = data;
            end
        end

        % Cell selection callback: UserAccountsTable
        function UserAccountsTableCellSelection(app, event)
            app.UserAccountsTable.UserData = event.Indices;
        end

        % Cell edit callback: UserAccountsTable
        function UserAccountsTableCellEdit(app, event)
            indices = event.Indices;
            data = app.UserAccountsTable.Data;
            if ~any(data.Administrator)
                uialert(app.LesionSynthesisToolboxUIFigure,'At least one account must be configured as administrator.','Administrator account error');
                data.Administrator(indices(1)) = true;
                app.UserAccountsTable.Data = data;
            end
        end

        % Cell edit callback: ScannerParamsTable
        function ScannerParamsTableCellEdit(app, event)
            app.ScannerDropDown.Items = app.ScannerParamsTable.Data.Name;
        end

        % Cell selection callback: ScannerParamsTable
        function ScannerParamsTableCellSelection(app, event)
            indices = event.Indices;
            app.ScannerParamsTable.UserData = indices;
        end

        % Value changed function: ProjectsListBox
        function ProjectsListBoxValueChanged(app, event)
            updateProjectSettings(app, app.ProjectsListBox.Value);
        end

        % Button pushed function: AddProjectButton
        function AddProjectButtonPushed(app, event)
            currentProjectName = app.ProjectsListBox.Value;
            [source, tf] = uilistdlg('New Project',...
                'Select an existing protocol to duplicate:',...
                app.ProjectsListBox.Items,...
                'SelectionMode','single',...
                'InitialValue',currentProjectName);
            if tf
                newProjectName = inputdlg('Enter a name for the new project','New Project',1,{''});
                if ~isempty(newProjectName)
                    newProjectName = newProjectName{1};
                    copyfile([projectDir(app) filesep source '.mat'], ...
                        [projectDir(app) filesep newProjectName '.mat']);
                    app.ProjectsListBox.Items = [app.ProjectsListBox.Items, newProjectName];
                    updateProjectSettings(app, newProjectName);
                end
            end
        end

        % Button pushed function: DeleteProjectButton
        function DeleteProjectButtonPushed(app, event)
            projectName = app.ProjectsListBox.Value;
            answer = uiconfirm(app.LesionSynthesisToolboxUIFigure,{['You are about to delete the configurations for the project: ' projectName],...
                'Do you wish to proceed?'},...
                'Delete confirmation',...
                'Options',{'Yes','No'},...
                'DefaultOption',2,...
                'CancelOption',2);
            if isequal(answer,'Yes')
                delete([projectDir(app) filesep projectName '.mat']);
                items = app.ProjectsListBox.Items;
                indx = strcmpi(items,projectName);
                items(indx) = [];
                app.ProjectsListBox.Items = items;
                if ~isempty(items)
                    indx = min(find(indx), length(items));
                    newProjectName = items{indx};
                    updateProjectSettings(app, newProjectName);
                end
            end
        end

        % Button pushed function: ShowProjectsDirButton
        function ShowProjectsDirButtonPushed(app, event)
            path = projectDir(app);
            disp(['Opening projects path: ' path])
            system(['explorer.exe "' path '"']);
        end

        % Button pushed function: ReconProtocolsDirBrowseButton
        function ReconProtocolsDirBrowseButtonPushed(app, event)
            value = app.ReconProtocolsDirEditField.Value;
            if exist(value,'dir') ~=7
                value = [app.appDataDir filesep 'Reconstruction Protocols'];
            end
            value = uigetdir(value,'Select reconstruction protocols directory');
            if ~isnumeric(value)
                app.ReconProtocolsDirEditField.Value = value;
                ReconProtocolsDirEditFieldValueChanged(app);
            end
        end

        % Value changed function: ReconProtocolsDirEditField
        function ReconProtocolsDirEditFieldValueChanged(app, event)
            updateReconProtocolsList(app)
        end

        % Button pushed function: FavoriteProjectButton
        function FavoriteProjectButtonPushed(app, event)
            app.FavoriteProjectButton.Icon = [app.appRootDir filesep 'Graphics' filesep 'litstarIcon_transp.png'];
            i = strcmpi(app.UserAccountsTable.Data.AccountName, app.user);
            app.UserAccountsTable.Data.Project{i} = app.project;
            saveUserData(app);
        end

        % Value changed function: LocationunitsDropDown
        function LocationunitsDropDownValueChanged(app, event)
            value = app.LocationunitsDropDown.Value;
            app.locationDisplayUnits = value;
            AvailSimulationsTableCellSelection(app)
        end

        % Value changed function: ReconProfileNamesListBox
        function ReconProfileNamesListBoxValueChanged(app, event)
            value = app.ReconProfileNamesListBox.Value;
            updateReconParamTable(app, app.ReconProfileParamasTable, value);
            app.SaveProfileButton.BackgroundColor = 'k';
        end

        % Button pushed function: AddReconProfileButton
        function AddReconProfileButtonPushed(app, event)
            currentReconName = app.ReconProfileNamesListBox.Value;
            [indx, tf] = uilistdlg('New Recon Profile',...
                'Select an existing reconstruction profile to duplicate:',...
                app.ReconProfileNamesListBox.Items,...
                'SelectionMode','single',...
                'InitialValue',currentReconName);
            if tf
                newReconProfileName = inputdlg('Enter a name for the new reconstruction profile','New Recon Profile',1,{''});
                if ~isempty(newReconProfileName)
                    newReconProfileName = newReconProfileName{1};
                    copyfile([reconProfilesDir(app) filesep indx '.mat'], ...
                        [reconProfilesDir(app) filesep newReconProfileName '.mat']);
                    app.ReconProfileNamesListBox.Items = [app.ReconProfileNamesListBox.Items, newReconProfileName];
                    updateProjectSettings(app, newReconProfileName);
                end
            end
        end

        % Button pushed function: DeleteReconProfileButton
        function DeleteReconProfileButtonPushed(app, event)
            reconProfileName = app.ReconProfileNamesListBox.Value;
            answer = uiconfirm(app.LesionSynthesisToolboxUIFigure, {['You are about to delete the reconstruction profile: ' reconProfileName],...
                'This may affect other users that rely on this profile.',...
                'Do you wish to proceed?'},...
                'Delete confirmation',...
                'Options',{'Yes','No'},...
                'DefaultOption',2,...
                'CancelOption',2);
            if isequal(answer,'Yes')
                delete([reconProfilesDir(app) filesep reconProfileName '.mat']);
                items = app.ReconProfileNamesListBox.Items;
                indx = strcmpi(items,reconProfileName);
                items(indx) = [];
                app.ReconProfileNamesListBox.Items = items;
                if ~isempty(items)
                    indx = min(find(indx), length(items));
                    newReconProfileName = items{indx};
                    updateReconParamTable(app, app.ReconProfileParamasTable, newReconProfileName);
                end
            end
        end

        % Button pushed function: SaveProfileButton
        function SaveProfileButtonPushed(app, event)
            reconProfileName = app.ReconProfileNamesListBox.Value;
            params = app.ReconProfileParamasTable.Data;
            params = cell2struct(params(:,2), params(:,1)); % TO DO - make a smarter table where not all parameters are editable (brokenable)
            save([reconProfilesDir(app) filesep reconProfileName '.mat'],'params');
            app.SaveProfileButton.BackgroundColor = 0.96*[1 1 1];
        end

        % Button pushed function: RestoreReconProfileParamsButton
        function RestoreReconProfileParamsButtonPushed(app, event)
            ReconProfileNamesListBoxValueChanged(app, event);
        end

        % Button pushed function: RefreshAvailReconsButton
        function RefreshAvailReconsButtonPushed(app, event)
            updateAvailRecons(app);
        end

        % Cell edit callback: ReconProfileParamasTable
        function ReconProfileParamasTableCellEdit(app, event)
            indices = event.Indices;
            %             supported in 2019b
            %             s = uistyle('Background','y');
            %             addStyle(app.ReconProfileParamasTable,s,'cell',indices);
            for i=1:3
                app.SaveProfileButton.BackgroundColor = 'k';
                pause(0.2)
                app.SaveProfileButton.BackgroundColor = 'y';
                pause(0.2)
            end
        end

        % Button pushed function: AnonymizeRawDataButton
        function AnonymizeRawDataButtonPushed(app, event)
            if ischar(event)
                dir = event;
            else
                if isempty(app.rawArchiveTable.UserData)
                    uialert(app.LesionSynthesisToolboxUIFigure, 'Select a study from the table of avaialble data to anonymize.', 'Anonymization error');
                    return
                end
                indices = app.rawArchiveTable.UserData.CurrentCell;
                dir = [app.RawImportArchiveEditField.Value filesep app.rawArchiveTable.Data{indices(1),1}];
            end
            file = listfiles('*.1',[dir filesep 'raw']);
            info = dicominfo([dir filesep 'raw' filesep file{1}]);
            count = getAnonCount(app);
            fields = {'Family Name','Given Name','Patient ID','Date of Birth'};
            originalData = {info.PatientName.FamilyName, info.PatientName.GivenName, info.PatientID, info.PatientBirthDate};
            newData = {sprintf('ANONp%05d',count), 'Anon', sprintf('%08d',count), [info.PatientBirthDate(1:4) '0101']};
            done = false;
            while ~done
                if 0
                    newData = inputdlg(strcat(fields, ' (', originalData, ')'), 'Anonymize raw patient data',1,newData);
                    drawnow
                    figure(app.LesionSynthesisToolboxUIFigure);
                else
                    anonApp = anonymizationUI(cell2struct(originalData,strrep(fields,' ',''),2), cell2struct(newData,strrep(fields,' ',''),2));
                    waitfor(anonApp,'UserData');
                    if isvalid(anonApp)
                        newData = struct2cell(anonApp.UserData);
                        anonApp.delete
                    else
                        newData = [];
                    end
                end

                if isempty(newData)
                    done = true;
                elseif exist([app.RawImportArchiveEditField.Value filesep newData{3}],'dir') ~= 7
                    done = true;
                else
                    uialert(app.LesionSynthesisToolboxUIFigure,['The specified patient ID (' newData{3} ') already exist in the raw data library. Please select a unique ID.'],'Anonymization ID conflict')
                    uiwait(app.LesionSynthesisToolboxUIFigure)
                end
            end

            if ~isempty(newData)
                if app.AnonymizeInplaceCheckBox.Value || ischar(event)
                    inplace = uiconfirm(app.LesionSynthesisToolboxUIFigure,'You are anonymizing in place. The original data will be destroyed. What would you like to do?',...
                        'Confirm in place anonymization','Icon','Warning','Options',{'In place','Duplicate','Cancel'},'DefaultOption',1,'CancelOption',3);
                    switch inplace
                        case 'In place'
                            inplace = true;
                        case 'Duplicate'
                            inplace = false;
                        case 'Cancel'
                            newData = [];
                    end
                else
                    inplace = false;
                end
                newHdr.PatientName = struct('FamilyName',newData{1}, 'GivenName',newData{2});
                newHdr.PatientID = newData{3};
                newHdr.PatientBirthDate = newData{4};
                ph = uiprogressdlg(app.LesionSynthesisToolboxUIFigure,'Title','Anonymizing raw data',...
                    'Message','Please wait patiently while the raw data is being anonymized...');
                anonGERawFiles(dir, newHdr, [app.RawImportArchiveEditField.Value filesep newHdr.PatientID], ph)
                if inplace
                    rmdir(dir,'s')
                end
                delete(ph);
                incrementAnonCount(app);
                if ~ischar(event)
                    updateDiscoveryIRTab(app);
                end
            end
        end

        % Button pushed function: LesionParamsCopyToClipboardButton
        function LesionParamsCopyToClipboardButtonPushed(app, event)
            data = app.AvailSimulationsTable.Data(app.AvailSimulationsTable.UserData.Indices(1),:)';
            str=[];
            for i=1:length(data)
                str = [str app.AvailSimulationsTable.ColumnName{i}, char(9), num2str(data{i}), newline];
            end
            data = [app.LesionTable.ColumnName'; app.LesionTable.Data];
            data{1,2} = [data{1,2} ' (C,S,T) [' app.LocationunitsDropDown.Value ']'];
            for ri = 1:size(data,1)
                for ci = 1:size(data,2)
                    if ci==1
                        del = '';
                    else
                        del = char(9); % tab delimeter
                    end
                    str = [str del num2str(data{ri,ci})];
                end
                if ri<size(data,1)
                    str = [str newline];
                end
            end
            clipboard('copy',str);
        end

        % Button pushed function: DeleteRawData
        function DeleteRawDataButtonPushed(app, event)
            if isempty(app.rawArchiveTable.UserData)
                uialert(app.LesionSynthesisToolboxUIFigure, 'Select a study from the table of available data first.', 'Delete error');
                return
            end
            indices = app.rawArchiveTable.UserData.CurrentCell;
            response = uiconfirm(app.LesionSynthesisToolboxUIFigure,{'You are about to delete the raw data for the following patient study:', ...
                ['     ID: ' app.rawArchiveTable.Data{indices(1),2}],...
                ['     Name: ' app.rawArchiveTable.Data{indices(1),3}],...
                ['     Study: ' app.rawArchiveTable.Data{indices(1),4}],...
                ['     Date: ' app.rawArchiveTable.Data{indices(1),5}],...
                '',...
                'This operation cannot be undone. Are you sure you want to proceed'},...
                'Delete confirmation',...
                'Icon','Warning',....
                'Options',{'Delete','Cancel'}, 'DefaultOption',2, 'CancelOption',2);
            if strcmp(response,'Delete')
                dir = [app.RawImportArchiveEditField.Value filesep app.rawArchiveTable.Data{indices(1),1}];
                rmdir(dir,'s')
            end
            updateDiscoveryIRTab(app)
        end

        % Value changed function: ddPETColormapDropDown
        function ddPETColormapDropDownValueChanged(app, event)
            plot_3D_volume(app,'VOE');

        end

        % Value changed function: ddFusionFactorSlider
        function ddFusionFactorSliderValueChanged(app, event)
            plot_3D_volume(app,'VOE');

        end

        % Value changed function: ddColorLimitSlider
        function ddColorLimitSliderValueChanged(app, event)
            plot_3D_volume(app,'VOE');
        end

        % Button pushed function: ReconSendToDICOMButton
        function ReconSendToDICOMButtonPushed(app, event)
            if isfield(app.AvailReconsTable.UserData, 'Indices')
                indices = app.AvailReconsTable.UserData.Indices;
                dir = [app.ImageReconArchiveEditField.Value filesep, ...
                    app.currentPatientID];
                currentReconName = strrep(cell2mat(app.AvailReconsTable.Data(indices(1),5)),' ','_');
                DICOMSend = struct('TargetHost',app.DICOMSendTargetHostNameEditField.Value,...
                    'TargetPort',app.DICOMSendTargetPortEditField.Value,...
                    'TargetAET',app.DICOMSendTargetAETEditField.Value,...
                    'SourceAET',app.DICOMSendSourceAETEditField.Value,...
                    'IncludeCT',app.DICOMSendIncludeCTCheckBox.Value);
                sendCmd1 = ['"' which('storescu.exe') '" -aet ' DICOMSend.SourceAET ' -aec ' DICOMSend.TargetAET ' -v ' DICOMSend.TargetHost ' ' num2str(DICOMSend.TargetPort) ' '];
                sendCmd = [sendCmd1 '+sd "' dir filesep currentReconName '_BaselineRecon"'];
                disp('Sending baseline recon PET to DICOM:')
                disp(sendCmd)
                [status, response] = system(sendCmd);
                disp('DICOM send response:')
                disp(response);

                sendCmd = [sendCmd1 '+sd "' dir filesep 'CTAC"'];
                disp('Sending CT to DICOM:')
                disp(sendCmd)
                [status, response] = system(sendCmd);
                disp('DICOM send response:')
                disp(response);

            end
        end

        % Button pushed function: SimulationSendToDICOMButton
        function SimulationSendToDICOMButtonPushed(app, event)
            if isfield(app.AvailSimulationsTable.UserData, 'Indices')
                indices = app.AvailSimulationsTable.UserData.Indices;
                currentPatientID = cell2mat(app.AvailSimulationsTable.Data(indices(1),1));
                dir = [app.SimulationArchiveEditField.Value filesep, ...
                    currentPatientID];
                DICOMSend = struct('TargetHost',app.DICOMSendTargetHostNameEditField.Value,...
                    'TargetPort',app.DICOMSendTargetPortEditField.Value,...
                    'TargetAET',app.DICOMSendTargetAETEditField.Value,...
                    'SourceAET',app.DICOMSendSourceAETEditField.Value,...
                    'IncludeCT',app.DICOMSendIncludeCTCheckBox.Value);
                sendCmd1 = ['"' which('storescu.exe') '" -aet ' DICOMSend.SourceAET ' -aec ' DICOMSend.TargetAET ' -v ' DICOMSend.TargetHost ' ' num2str(DICOMSend.TargetPort) ' '];
                sendCmd = [sendCmd1 '+sd "' dir filesep cell2mat(app.AvailSimulationsTable.Data(indices(1),2)) filesep cell2mat(app.AvailSimulationsTable.Data(indices(1),3)) '_DICOM"'];
                disp('Sending simulation PET to DICOM:')
                disp(sendCmd)
                [status, response] = system(sendCmd);
                disp('DICOM send response:')
                disp(response);

                dicomDir = [dir filesep 'Baseline_PET' filesep 'CTAC'];
                if ~exist(dicomDir, 'dir')
                    dicomDir = ['"' app.ImageReconArchiveEditField.Value filesep currentPatientID filesep 'CTAC"' ];
                end
                sendCmd = [sendCmd1 '+sd ' dicomDir];
                disp('Sending CT to DICOM:')
                disp(sendCmd)
                [status, response] = system(sendCmd);
                disp('DICOM send response:')
                disp(response);

            end
        end

        % Value changed function: DefaultReconProtocolDropDown
        function DefaultReconProtocolDropDownValueChanged(app, event)
            value = app.DefaultReconProtocolDropDown.Value;
            app.RawReconProfilesDropDown.Value = value;
            app.SimulationReconProfileDropDown.Value = value;
        end

        % Value changed function: SimulationObjectsListBox
        function SimulationObjectsListBoxValueChanged(app, event)
            app.SimulationObjectsListBox.ItemsData = 1:length(app.SimulationObjectsListBox.Items);
            value = app.SimulationObjectsListBox.Value;

            %app.ObjectNameEditField.Value = app.lesionInfo{value}.name;
            loc = convertImgLocationUnits(app.currentImgData.hdr, app.lesionInfo{value}.loc, 'mm from center', app.locationDisplayUnits);
            app.ObjectLocationEditField.Value = num2str(loc);
            app.ShapeDropDown.Value = app.lesionInfo{value}.shape;
            app.RadiusmmEditField.Value = app.lesionInfo{value}.rad;
            app.PETIntensityEditField.Value = app.lesionInfo{value}.PTval;
            app.PETIntensityAddModeDropDown.Value = app.lesionInfo{value}.PTadditionMode;
            app.CTIntensityHUEditField.Value = app.lesionInfo{value}.CTval;
            app.PETIntensityModeDropDown.Value = app.lesionInfo{value}.mode;
            loc = convertImgLocationUnits(app.currentImgData.hdr, loc, app.locationDisplayUnits, 'pixel');
            loc = loc([2 1 3]);
            plot_3D_volume(app,'VOE',loc)
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            updateSimulationJobQueue(app)
        end

        % Button pushed function: DownloadlesionsummarytableButton
        function DownloadlesionsummarytableButtonPushed(app, event)
            surroundingMargin = 20; %mm
            [file, dir] = uiputfile('*.xlsx','Save lesion ground truth table',[app.SimulationArchiveEditField.Value filesep app.project '-ground truth.xlsx']);
            if isequal(file,0)
                return
            end

            saveFile = [dir file];
            dir = app.SimulationArchiveEditField.Value;
            data = app.AvailSimulationsTable.Data;
            n = size(data,1);
            header = {'Patient ID',...
                'Simulation Name',...
                'Reconstruction Profile',...
                'Series Description	MAC LST',...
                'Simulation Date',...
                'Lesion Name',...
                ['Location Coronal [' app.LocationunitsDropDown.Value ']'],...
                ['Location Sagittal [' app.LocationunitsDropDown.Value ']'],...
                ['Location Transaxial [' app.LocationunitsDropDown.Value ']'],...
                'PET Intensity Mode',...
                'PET Intensity Value',...
                'CT Intensity Value',...
                'Shape',...
                'Shape Parameters',...
                'Reference Lesion Activity (Bq/cc)',...
                'Baseline Background activity (Bq/cc)',...
                'Target Lesion Activity (Bq/cc)',...
                'Surrounding Margin (mm)',...
                'Surrounding Mean PET Intensity',...
                'Surrounding SD PET Intensity',...
                'Surrounding Min PET Intensity',...
                'Surrounding Max PET Intensity'};
            lesionTable = cell(1,length(header));
            lineIndex = 1;
            pb = uiprogressdlg(app.LesionSynthesisToolboxUIFigure, 'Title','Export lesion ground truth data',...
                'Message', ['Saving lesion ground truth to ' saveFile],...
                'Value',0);
            for fi = 1:n
                d = load([dir filesep data{fi,1} filesep data{fi,2} filesep data{fi,3} '_LesionParams.mat']);
                baselineImgData = load([d.info.patDataDir filesep d.info.reconProfile '_fir3D.mat']);
                [~, lesions, ~] = makeLesionImage(baselineImgData, d, surroundingMargin);

                for li = 1:length(d.lesion)
                    loc = convertImgLocationUnits(d.lesion{li}.hdr, d.lesion{li}.loc, d.lesion{li}.locUnits, app.LocationunitsDropDown.Value);
                    lesionTable(lineIndex,:) = {d.info.patientID,...
                        d.info.reconName,...
                        d.info.reconProfile,...
                        d.info.simParams.SeriesDesc,...
                        d.info.simDateTime,...
                        d.lesion{li}.name,...
                        loc(1),...
                        loc(2),...
                        loc(3),...
                        d.lesion{li}.mode,...
                        d.lesion{li}.PTval,...
                        d.lesion{li}.CTval,...
                        d.lesion{li}.shape,...
                        d.lesion{li}.rad,...
                        lesions{li}.referencePTintensity,...
                        lesions{li}.baselineBackgroundPTintesity,...
                        lesions{li}.PTintensity,...
                        surroundingMargin,...
                        lesions{li}.baselineSurroundingPTintensity,...
                        lesions{li}.baselineSurroundingPTnoise,...
                        lesions{li}.baselineSurroundingPTmin,...
                        lesions{li}.baselineSurroundingPTmax};
                    lineIndex = lineIndex + 1;
                end
                pb.Value = fi/n;
                pb.Message = {['Saving lesion ground truth data for: ' strjoin(data(fi,1:3), ' - ')],...
                    '',...
                    ['To: ' saveFile]};
            end
            lesionTable = cell2table(lesionTable,'VariableNames',header);
            writetable(lesionTable,saveFile,'WriteMode','overwritesheet','sheet','Lesion Ground Truth');
            close(pb);

            selection = uiconfirm(app.LesionSynthesisToolboxUIFigure,['Lesions successfully exported to ' saveFile], 'Lesion ground truth saved',...
                'Icon','success',...
                'Options',{'Open file','Close'});
            if strcmpi(selection,'Open file')
                system(saveFile)
            end
        end

        % Button pushed function: btn_logout
        function btn_logoutButtonPushed(app, event)
            app.MessageLampLabel.Text = 'Workstation Locked';
            app.MessageLamp.Color = 'r'; % Locked
            app.user = '';
            logout(app);
        end

        % Button pushed function: RetrieveLesionsButton
        function RetrieveLesionsButtonPushed(app, event)
            app.VOETabGroup.SelectedTab = app.DefineDiseaseTab;

            if ~isempty(app.SimulationObjectsListBox.Items) || ~isempty(app.RefObjectsListBox.Items)
                selection = uiconfirm(app.LesionSynthesisToolboxUIFigure,'There is a study open with defined objects. This will remove all defined objects', 'Warning','Icon','warning');

                switch selection
                    case 'OK'
                        clearSimulationTab(app);
                        retrieveLesions(app)

                    case 'Cancel'
                        % Nothing happens
                end

            else
                retrieveLesions(app)
            end


        end

        % Value changed function: VarianceSlider
        function VarianceSliderValueChanged(app, event)
            value = app.VarianceSlider.Value;
            value_int = round(value);
            app.VarianceSlider.Value = value_int;
        end

        % Value changed function: ShapeDropDown
        function ShapeDropDownValueChanged(app, event)
            value = app.ShapeDropDown.Value;
            if strcmpi(value, "Blobby sphere (homo)")
                app.VarianceSlider.Visible = true;
                app.VarianceSliderLabel.Visible = true;
            else
                app.VarianceSlider.Visible = false;
                app.VarianceSliderLabel.Visible = false;
            end
        end

        % Value changed function: rlPETColormapDropDown
        function rlPETColormapDropDownValueChanged(app, event)
            plot_3D_volume(app,'lesionReview');

        end

        % Value changed function: rlFusionFactorSlider
        function rlFusionFactorSliderValueChanged(app, event)
            plot_3D_volume(app,'lesionReview');

        end

        % Close request function: LesionSynthesisToolboxUIFigure
        function LesionSynthesisToolboxUIFigureCloseRequest(app, event)
            stop(app.LSTServiceStatusCheckTimer); % stop the check status timer, as the indicator will no longer exist.
            delete(app.LSTServiceStatusCheckTimer);
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LesionSynthesisToolboxUIFigure = uifigure('Visible', 'off');
            app.LesionSynthesisToolboxUIFigure.Name = 'Lesion Synthesis Toolbox';
            app.LesionSynthesisToolboxUIFigure.Icon = 'LST Icon 4.png';
            app.LesionSynthesisToolboxUIFigure.CloseRequestFcn = createCallbackFcn(app, @LesionSynthesisToolboxUIFigureCloseRequest, true);
            app.LesionSynthesisToolboxUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.LesionSynthesisToolboxUIFigure.WindowScrollWheelFcn = createCallbackFcn(app, @LesionSynthesisToolboxUIFigureWindowScrollWheel, true);
            app.GridLayout = uigridlayout(app.LesionSynthesisToolboxUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.btn_newsim = uibutton(app.LeftPanel, 'push');
            app.btn_newsim.Icon = 'simulationIcon_trans.png';
            app.btn_newsim.Enable = 'off';
            app.btn_newsim.Visible = 'off';
            app.btn_newsim.Tooltip = {'New Simulation'};
            app.btn_newsim.Text = '';
            app.btn_logout = uibutton(app.LeftPanel, 'push');
            app.btn_logout.ButtonPushedFcn = createCallbackFcn(app, @btn_logoutButtonPushed, true);
            app.btn_logout.Icon = 'lockIcon_green.png';
            app.btn_logout.Tooltip = {'Lock workstation'};
            app.btn_logout.Text = '';
            app.Image2 = uiimage(app.LeftPanel);
            app.Image2.ImageSource = 'Banner_LST.png';
            app.RightPanel = uipanel(app.GridLayout);
            app.HeadTabGroup = uitabgroup(app.RightPanel);
            app.HeadTabGroup.SelectionChangedFcn = createCallbackFcn(app, @HeadTabGroupSelectionChanged, true);
            app.LoginTab = uitab(app.HeadTabGroup);
            app.LoginTab.Title = 'Login';
            app.Image = uiimage(app.LoginTab);
            app.Image.ImageSource = 'splash_logo.png';
            app.LoginButton = uibutton(app.LoginTab, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = 'Login';
            app.UsernameEditFieldLabel = uilabel(app.LoginTab);
            app.UsernameEditFieldLabel.Text = 'Username:';
            app.UsernameEditField = uieditfield(app.LoginTab, 'text');
            app.PasswordEditFieldLabel = uilabel(app.LoginTab);
            app.PasswordEditFieldLabel.Text = 'Password:';
            app.PasswordEditField = uieditfield(app.LoginTab, 'text');
            app.PasswordEditField.ValueChangedFcn = createCallbackFcn(app, @PasswordEditFieldValueChanged, true);
            app.DiscoveryDRTab = uitab(app.HeadTabGroup);
            app.DiscoveryDRTab.Tooltip = {'Retreive patient data from the Discovery console.'};
            app.DiscoveryDRTab.Title = 'DiscoveryDR';
            app.rawImportTable = uitable(app.DiscoveryDRTab);
            app.rawImportTable.ColumnName = {'Patient Directory'; 'Patient ID'; 'Patient Name'; 'Number of Associated Series'};
            app.rawImportTable.RowName = {};
            app.rawImportTable.ColumnSortable = [true true true false];
            app.rawImportTable.CellSelectionCallback = createCallbackFcn(app, @rawImportTableCellSelection, true);
            app.seriesImportTable = uitable(app.DiscoveryDRTab);
            app.seriesImportTable.ColumnName = {'Import Series'; 'Series Path'; 'Series Description'; 'Acqusition Date'; 'Acqusition Time'};
            app.seriesImportTable.RowName = {};
            app.seriesImportTable.CellEditCallback = createCallbackFcn(app, @seriesImportTableCellEdit, true);
            app.seriesImportTable.DisplayDataChangedFcn = createCallbackFcn(app, @seriesImportTableDisplayDataChanged, true);
            app.ConnectButton = uibutton(app.DiscoveryDRTab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.ScannerDropDownLabel = uilabel(app.DiscoveryDRTab);
            app.ScannerDropDownLabel.Text = 'Scanner';
            app.ScannerDropDown = uidropdown(app.DiscoveryDRTab);
            app.ScannerDropDown.Items = {'Add New Scanner'};
            app.ScannerDropDown.ValueChangedFcn = createCallbackFcn(app, @ScannerDropDownValueChanged, true);
            app.ScannerDropDown.Value = 'Add New Scanner';
            app.ImportSelectedStudiesButton = uibutton(app.DiscoveryDRTab, 'push');
            app.ImportSelectedStudiesButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSelectedStudiesButtonPushed, true);
            app.ImportSelectedStudiesButton.Text = 'Import Selected Studies';
            app.AnonymizeImportedStudiesCheckBox = uicheckbox(app.DiscoveryDRTab);
            app.AnonymizeImportedStudiesCheckBox.Text = 'Anonymize Imported Studies';
            app.StudyInvestigatorLabel = uilabel(app.DiscoveryDRTab);
            app.StudyInvestigatorLabel.Text = 'Study Investigator';
            app.DateRangeDropDownLabel = uilabel(app.DiscoveryDRTab);
            app.DateRangeDropDownLabel.Text = 'Date Range';
            app.DateRangeDropDown = uidropdown(app.DiscoveryDRTab);
            app.DateRangeDropDown.Items = {'Today', 'Past Week', 'Past 2 Weeks', 'Past 3 Weeks', 'Past Month', 'All'};
            app.DateRangeDropDown.Value = 'Today';
            app.DiscoveryIRTab = uitab(app.HeadTabGroup);
            app.DiscoveryIRTab.Tooltip = {'Perform image reconstruction of raw patient data.'};
            app.DiscoveryIRTab.Title = 'DiscoveryIR';
            app.RawReconParamsTable = uitable(app.DiscoveryIRTab);
            app.RawReconParamsTable.ColumnName = {'IR Parameter'; 'Value'};
            app.RawReconParamsTable.RowName = {};
            app.ReconProfilesDropDownLabel = uilabel(app.DiscoveryIRTab);
            app.ReconProfilesDropDownLabel.Text = 'Recon. Profiles';
            app.RawReconProfilesDropDown = uidropdown(app.DiscoveryIRTab);
            app.RawReconProfilesDropDown.Items = {'Default (TOF OSEM 2iter 24sub)'};
            app.RawReconProfilesDropDown.ValueChangedFcn = createCallbackFcn(app, @RawReconProfilesDropDownValueChanged, true);
            app.RawReconProfilesDropDown.Tooltip = {'Image Reconstruction Settings'};
            app.RawReconProfilesDropDown.Value = 'Default (TOF OSEM 2iter 24sub)';
            app.btn_saveDISettings_3 = uibutton(app.DiscoveryIRTab, 'push');
            app.btn_saveDISettings_3.Icon = 'btn_saveicon.png';
            app.btn_saveDISettings_3.Enable = 'off';
            app.btn_saveDISettings_3.Visible = 'off';
            app.btn_saveDISettings_3.Tooltip = {'Save Recon Profile'};
            app.btn_saveDISettings_3.Text = '';
            app.QueueImageReconstructionButton = uibutton(app.DiscoveryIRTab, 'push');
            app.QueueImageReconstructionButton.ButtonPushedFcn = createCallbackFcn(app, @QueueImageReconstructionButtonPushed, true);
            app.QueueImageReconstructionButton.Tooltip = {'Queue Recon.'};
            app.QueueImageReconstructionButton.Text = 'Queue Reconstruction';
            app.AvailableStudiesLabel = uilabel(app.DiscoveryIRTab);
            app.AvailableStudiesLabel.Text = 'Available Studies';
            app.rawArchiveTable = uitable(app.DiscoveryIRTab);
            app.rawArchiveTable.ColumnName = {'Study Folder'; 'Patient ID'; 'Patient Name'; 'Study Description'; 'Acqusition Date'};
            app.rawArchiveTable.RowName = {};
            app.rawArchiveTable.ColumnSortable = true;
            app.rawArchiveTable.CellSelectionCallback = createCallbackFcn(app, @rawArchiveTableCellSelection, true);
            app.ImageReconstructionJobQueueLabel = uilabel(app.DiscoveryIRTab);
            app.ImageReconstructionJobQueueLabel.Text = 'Image Reconstruction Job Queue';
            app.ImageReconstructionJobQueueListBox = uilistbox(app.DiscoveryIRTab);
            app.ImageReconstructionJobQueueListBox.Items = {};
            app.ImageReconstructionJobQueueListBox.Value = {};
            app.StartProcessingServiceButton_2 = uibutton(app.DiscoveryIRTab, 'state');
            app.StartProcessingServiceButton_2.ValueChangedFcn = createCallbackFcn(app, @InitiateProcessingService, true);
            app.StartProcessingServiceButton_2.Text = 'Start Processing Service';
            app.RemoveJobButton = uibutton(app.DiscoveryIRTab, 'push');
            app.RemoveJobButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveJobButtonPushed, true);
            app.RemoveJobButton.Tooltip = {'Remove the selected job from the baseline reconstruction queue.'};
            app.RemoveJobButton.Text = 'Remove Job';
            app.AnonymizeRawDataButton = uibutton(app.DiscoveryIRTab, 'push');
            app.AnonymizeRawDataButton.ButtonPushedFcn = createCallbackFcn(app, @AnonymizeRawDataButtonPushed, true);
            app.AnonymizeRawDataButton.Text = 'Anonymize Data';
            app.AnonymizeInplaceCheckBox = uicheckbox(app.DiscoveryIRTab);
            app.AnonymizeInplaceCheckBox.Tooltip = {'In place erases the original data and replaces is with anonymized data. Otherwise both are kept.'};
            app.AnonymizeInplaceCheckBox.Text = 'In place';
            app.DeleteRawData = uibutton(app.DiscoveryIRTab, 'push');
            app.DeleteRawData.ButtonPushedFcn = createCallbackFcn(app, @DeleteRawDataButtonPushed, true);
            app.DeleteRawData.Icon = 'btn_garbage.png';
            app.DeleteRawData.Text = 'Delete Raw Data';
            app.DiscoveryVOETab = uitab(app.HeadTabGroup);
            app.DiscoveryVOETab.Tooltip = {'View image data and add synthetic lesions to simulate.'};
            app.DiscoveryVOETab.SizeChangedFcn = createCallbackFcn(app, @VOETabGroupSelectionChanged, true);
            app.DiscoveryVOETab.Title = 'DiscoveryVOE';
            app.VOETabGroup = uitabgroup(app.DiscoveryVOETab);
            app.VOETabGroup.SelectionChangedFcn = createCallbackFcn(app, @VOETabGroupSelectionChanged, true);
            app.SelectPatientTab = uitab(app.VOETabGroup);
            app.SelectPatientTab.Tooltip = {'Select reconstructed patient data to view and add syntheitic lesions.'};
            app.SelectPatientTab.Title = '(1) - Select Patient';
            app.AvailReconsTable = uitable(app.SelectPatientTab);
            app.AvailReconsTable.ColumnName = {'Patient ID'; 'Patient Name'; 'Study Description'; 'Acqusition Date'; 'Recon Name'; 'Recon Time'};
            app.AvailReconsTable.RowName = {};
            app.AvailReconsTable.ColumnSortable = true;
            app.AvailReconsTable.CellSelectionCallback = createCallbackFcn(app, @AvailReconsTableCellSelection, true);
            app.ReconstructedPatientStudiesLabel = uilabel(app.SelectPatientTab);
            app.ReconstructedPatientStudiesLabel.Text = 'Reconstructed Patient Studies';
            app.ScanPreviewPanel = uipanel(app.SelectPatientTab);
            app.ScanPreviewPanel.Title = 'Scan Preview';
            app.SliceY = uiaxes(app.ScanPreviewPanel);
            app.SliceY.XTick = [];
            app.SliceY.YTick = [];
            app.SliceZ = uiaxes(app.ScanPreviewPanel);
            ylabel(app.SliceZ, 'Y')
            app.SliceZ.XTick = [];
            app.SliceZ.YTick = [];
            app.SliceX = uiaxes(app.ScanPreviewPanel);
            app.SliceX.XTick = [];
            app.SliceX.YTick = [];
            app.SelectStudyButton = uibutton(app.ScanPreviewPanel, 'push');
            app.SelectStudyButton.ButtonPushedFcn = createCallbackFcn(app, @SelectStudyButtonPushed, true);
            app.SelectStudyButton.Text = 'Select Study';
            app.Slider_3Label = uilabel(app.ScanPreviewPanel);
            app.Slider_3Label.Text = '';
            app.spColorLimitSlider = uislider(app.ScanPreviewPanel);
            app.spColorLimitSlider.ValueChangingFcn = createCallbackFcn(app, @spColorLimitSliderValueChanging, true);
            app.RefreshAvailReconsButton = uibutton(app.SelectPatientTab, 'push');
            app.RefreshAvailReconsButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshAvailReconsButtonPushed, true);
            app.RefreshAvailReconsButton.Icon = 'btn_refresh.png';
            app.RefreshAvailReconsButton.Text = 'Refresh';
            app.ReconSendToDICOMButton = uibutton(app.SelectPatientTab, 'push');
            app.ReconSendToDICOMButton.ButtonPushedFcn = createCallbackFcn(app, @ReconSendToDICOMButtonPushed, true);
            app.ReconSendToDICOMButton.Icon = 'exportIcon.png';
            app.ReconSendToDICOMButton.Text = 'Send to DICOM';
            app.DefineDiseaseTab = uitab(app.VOETabGroup);
            app.DefineDiseaseTab.Tooltip = {'Define synthetic lesions to insert into the patient data.'};
            app.DefineDiseaseTab.Title = '(2) - Define Disease';
            app.DVolumeViewerPanel = uipanel(app.DefineDiseaseTab);
            app.DVolumeViewerPanel.Title = '3D Volume Viewer';
            app.ddSliceY = uiaxes(app.DVolumeViewerPanel);
            app.ddSliceY.XAxisLocation = 'origin';
            app.ddSliceY.XTick = [];
            app.ddSliceY.YAxisLocation = 'origin';
            app.ddSliceY.YTick = [];
            app.ddSliceZ = uiaxes(app.DVolumeViewerPanel);
            app.ddSliceZ.XAxisLocation = 'origin';
            app.ddSliceZ.XTick = [];
            app.ddSliceZ.YAxisLocation = 'origin';
            app.ddSliceZ.YTick = [];
            app.ddSliceX = uiaxes(app.DVolumeViewerPanel);
            app.ddSliceX.XAxisLocation = 'origin';
            app.ddSliceX.XTick = [];
            app.ddSliceX.YAxisLocation = 'origin';
            app.ddSliceX.YTick = [];
            app.PatientNameLabel = uilabel(app.DVolumeViewerPanel);
            app.PatientNameLabel.Text = 'Patient Name: ';
            app.PatientIDLabel = uilabel(app.DVolumeViewerPanel);
            app.PatientIDLabel.Text = 'Patient ID:';
            app.Label = uilabel(app.DVolumeViewerPanel);
            app.Label.Text = '';
            app.ddColorLimitSlider = uislider(app.DVolumeViewerPanel);
            app.ddColorLimitSlider.ValueChangedFcn = createCallbackFcn(app, @ddColorLimitSliderValueChanged, true);
            app.ddColorLimitSlider.ValueChangingFcn = createCallbackFcn(app, @ddColorLimitSliderValueChanging, true);
            app.ddColorLimitSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.FusionFactorSliderLabel = uilabel(app.DVolumeViewerPanel);
            app.FusionFactorSliderLabel.Text = 'Fusion Factor';
            app.ddFusionFactorSlider = uislider(app.DVolumeViewerPanel);
            app.ddFusionFactorSlider.Limits = [0 1];
            app.ddFusionFactorSlider.MajorTicks = [0 0.5 1];
            app.ddFusionFactorSlider.MajorTickLabels = {'CT', 'Even', 'PET'};
            app.ddFusionFactorSlider.ValueChangedFcn = createCallbackFcn(app, @ddFusionFactorSliderValueChanged, true);
            app.ddFusionFactorSlider.MinorTicks = [0.1 0.2 0.3 0.4 0.6 0.7 0.8 0.9];
            app.ddFusionFactorSlider.Value = 0.5;
            app.PETColormapDropDownLabel = uilabel(app.DVolumeViewerPanel);
            app.PETColormapDropDownLabel.Text = 'PET Colormap';
            app.ddPETColormapDropDown = uidropdown(app.DVolumeViewerPanel);
            app.ddPETColormapDropDown.Items = {'Hot Metal', 'Hot', 'Parula', 'Jet', 'HSV', 'Red White Blue'};
            app.ddPETColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ddPETColormapDropDownValueChanged, true);
            app.ddPETColormapDropDown.Value = 'Hot';
            app.ReconstructionProfileLabel = uilabel(app.DVolumeViewerPanel);
            app.ReconstructionProfileLabel.Text = 'Reconstruction Profile:';
            app.DefineDiseaseObjectPanel = uipanel(app.DefineDiseaseTab);
            app.DefineDiseaseObjectPanel.Title = 'Define Disease Object';
            app.ObjectNameEditFieldLabel = uilabel(app.DefineDiseaseObjectPanel);
            app.ObjectNameEditFieldLabel.Text = 'Object Name';
            app.ObjectNameEditField = uieditfield(app.DefineDiseaseObjectPanel, 'text');
            app.ObjectLocationEditFieldLabel = uilabel(app.DefineDiseaseObjectPanel);
            app.ObjectLocationEditFieldLabel.Text = 'Object Location';
            app.ObjectLocationEditField = uieditfield(app.DefineDiseaseObjectPanel, 'text');
            app.ObjectLocationEditField.ValueChangedFcn = createCallbackFcn(app, @ObjectLocationEditFieldValueChanged, true);
            app.ShapeDropDownLabel = uilabel(app.DefineDiseaseObjectPanel);
            app.ShapeDropDownLabel.Text = 'Shape';
            app.ShapeDropDown = uidropdown(app.DefineDiseaseObjectPanel);
            app.ShapeDropDown.Items = {'Sphere (homo)', 'Blobby sphere (homo)'};
            app.ShapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ShapeDropDownValueChanged, true);
            app.ShapeDropDown.Value = 'Sphere (homo)';
            app.RadiusmmEditFieldLabel = uilabel(app.DefineDiseaseObjectPanel);
            app.RadiusmmEditFieldLabel.Text = 'Radius (mm)';
            app.RadiusmmEditField = uieditfield(app.DefineDiseaseObjectPanel, 'numeric');
            app.RadiusmmEditField.Limits = [0 Inf];
            app.PETIntensityModeDropDownLabel = uilabel(app.DefineDiseaseObjectPanel);
            app.PETIntensityModeDropDownLabel.Text = 'PET Intensity Mode';
            app.PETIntensityModeDropDown = uidropdown(app.DefineDiseaseObjectPanel);
            app.PETIntensityModeDropDown.Items = {'Bq/cc', 'SUV', 'Lesion:Background'};
            app.PETIntensityModeDropDown.Value = 'Lesion:Background';
            app.PETIntensityEditFieldLabel = uilabel(app.DefineDiseaseObjectPanel);
            app.PETIntensityEditFieldLabel.Text = 'PET Intensity ';
            app.PETIntensityEditField = uieditfield(app.DefineDiseaseObjectPanel, 'numeric');
            app.CTIntensityHUEditFieldLabel = uilabel(app.DefineDiseaseObjectPanel);
            app.CTIntensityHUEditFieldLabel.Text = 'CT Intensity (HU)';
            app.CTIntensityHUEditField = uieditfield(app.DefineDiseaseObjectPanel, 'numeric');
            app.CTIntensityHUEditField.Limits = [-3000 3000];
            app.CTIntensityHUEditField.ValueDisplayFormat = '%.0f';
            app.CTIntensityHUEditField.Value = 50;
            app.AddObjectButton = uibutton(app.DefineDiseaseObjectPanel, 'push');
            app.AddObjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddObjectButtonPushed, true);
            app.AddObjectButton.Icon = 'plusIcon_transp.png';
            app.AddObjectButton.Text = {'Add '; 'Object'};
            app.PETIntensityAddModeDropDown = uidropdown(app.DefineDiseaseObjectPanel);
            app.PETIntensityAddModeDropDown.Items = {'Final (Homogenous)', 'Final (Maintain Texture)', 'Incremental', ''};
            app.PETIntensityAddModeDropDown.Tooltip = {'The means by which the PET intensity is specified final outcome, or how much to add. '};
            app.PETIntensityAddModeDropDown.Value = 'Final (Homogenous)';
            app.CTIntensityAddModeDropDown = uidropdown(app.DefineDiseaseObjectPanel);
            app.CTIntensityAddModeDropDown.Items = {'Final (Homogenous)', 'Final (Maintain Texture)', 'Incremental', ''};
            app.CTIntensityAddModeDropDown.Tooltip = {'The means by which the PET intensity is specified final outcome, or how much to add. '};
            app.CTIntensityAddModeDropDown.Value = 'Final (Homogenous)';
            app.VarianceSliderLabel = uilabel(app.DefineDiseaseObjectPanel);
            app.VarianceSliderLabel.Visible = 'off';
            app.VarianceSliderLabel.Text = 'Variance';
            app.VarianceSlider = uislider(app.DefineDiseaseObjectPanel);
            app.VarianceSlider.Limits = [1 10];
            app.VarianceSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.VarianceSlider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.VarianceSlider.ValueChangedFcn = createCallbackFcn(app, @VarianceSliderValueChanged, true);
            app.VarianceSlider.MinorTicks = [1 2 3 4 5 6 7 8 9 10];
            app.VarianceSlider.Visible = 'off';
            app.VarianceSlider.Value = 1;
            app.DiseaseObjectstoSimulatePanel = uipanel(app.DefineDiseaseTab);
            app.DiseaseObjectstoSimulatePanel.Title = 'Disease Objects to Simulate';
            app.SimulationObjectsListBox = uilistbox(app.DiseaseObjectstoSimulatePanel);
            app.SimulationObjectsListBox.Items = {};
            app.SimulationObjectsListBox.ValueChangedFcn = createCallbackFcn(app, @SimulationObjectsListBoxValueChanged, true);
            app.SimulationObjectsListBox.Value = {};
            app.RemoveObjectButton = uibutton(app.DiseaseObjectstoSimulatePanel, 'push');
            app.RemoveObjectButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveObjectButtonPushed, true);
            app.RemoveObjectButton.Icon = 'minusIcon_transp.png';
            app.RemoveObjectButton.Text = {'Remove '; 'Object'};
            app.SimulationSettingsPanel = uipanel(app.DefineDiseaseTab);
            app.SimulationSettingsPanel.Title = 'Simulation Settings';
            app.QueueSimButton = uibutton(app.SimulationSettingsPanel, 'push');
            app.QueueSimButton.ButtonPushedFcn = createCallbackFcn(app, @QueueSimButtonPushed, true);
            app.QueueSimButton.Text = {'Queue'; 'Sim'};
            app.SimulationReconParamsTable = uitable(app.SimulationSettingsPanel);
            app.SimulationReconParamsTable.ColumnName = {'Parameter'; 'Value'};
            app.SimulationReconParamsTable.RowName = {};
            app.ImageReconstructionProfileLabel = uilabel(app.SimulationSettingsPanel);
            app.ImageReconstructionProfileLabel.Text = 'Image Reconstruction Profile';
            app.SimulationReconProfileDropDown = uidropdown(app.SimulationSettingsPanel);
            app.SimulationReconProfileDropDown.Items = {'Default (TOF OSEM 2iter 24sub)'};
            app.SimulationReconProfileDropDown.ValueChangedFcn = createCallbackFcn(app, @SimulationReconProfileDropDownValueChanged, true);
            app.SimulationReconProfileDropDown.Value = 'Default (TOF OSEM 2iter 24sub)';
            app.BedRangeDropDownLabel = uilabel(app.SimulationSettingsPanel);
            app.BedRangeDropDownLabel.Text = 'Bed Range';
            app.BedRangeDropDown = uidropdown(app.SimulationSettingsPanel);
            app.BedRangeDropDown.Items = {'All beds', 'Bed range with lesions'};
            app.BedRangeDropDown.Value = 'All beds';
            app.KeeplesionsCheckBox = uicheckbox(app.SimulationSettingsPanel);
            app.KeeplesionsCheckBox.Text = 'Keep lesions';
            app.DefineReferenceObjectPanel = uipanel(app.DefineDiseaseTab);
            app.DefineReferenceObjectPanel.Title = 'Define Reference Object';
            app.ObjectNameEditField_2Label = uilabel(app.DefineReferenceObjectPanel);
            app.ObjectNameEditField_2Label.Text = 'Object Name';
            app.RefObjectNameEditField = uieditfield(app.DefineReferenceObjectPanel, 'text');
            app.RefObjectNameEditField.Value = 'Ref 1';
            app.ObjectLocationEditField_2Label = uilabel(app.DefineReferenceObjectPanel);
            app.ObjectLocationEditField_2Label.Text = 'Object Location';
            app.RefObjectLocationEditField = uieditfield(app.DefineReferenceObjectPanel, 'text');
            app.ShapeDropDown_2Label = uilabel(app.DefineReferenceObjectPanel);
            app.ShapeDropDown_2Label.Text = 'Shape';
            app.RefShapeDropDown = uidropdown(app.DefineReferenceObjectPanel);
            app.RefShapeDropDown.Items = {'Sphere (homo)'};
            app.RefShapeDropDown.Value = 'Sphere (homo)';
            app.RadiusmmEditField_2Label = uilabel(app.DefineReferenceObjectPanel);
            app.RadiusmmEditField_2Label.Text = 'Radius (mm)';
            app.RefRadiusmmEditField = uieditfield(app.DefineReferenceObjectPanel, 'numeric');
            app.RefRadiusmmEditField.Limits = [0 600];
            app.RefRadiusmmEditField.Value = 20;
            app.AddRefObjectButton = uibutton(app.DefineReferenceObjectPanel, 'push');
            app.AddRefObjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddRefObjectButtonPushed, true);
            app.AddRefObjectButton.Text = 'Add Reference Object';
            app.RefObjectsListBox = uilistbox(app.DefineReferenceObjectPanel);
            app.RefObjectsListBox.Items = {};
            app.RefObjectsListBox.Value = {};
            app.RemoveRefObjectButton = uibutton(app.DefineReferenceObjectPanel, 'push');
            app.RemoveRefObjectButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveRefObjectButtonPushed, true);
            app.RemoveRefObjectButton.Text = 'Remove Reference Object';
            app.JobQueueTab = uitab(app.VOETabGroup);
            app.JobQueueTab.Tooltip = {'Review and manage lesion synthesis job queue.'};
            app.JobQueueTab.Title = '(3) - Job Queue';
            app.SimsInQueueTable = uitable(app.JobQueueTab);
            app.SimsInQueueTable.ColumnName = {'Simulation Name'; 'Patient ID'; '# of Objects'; 'Processing Directory'; 'Archive Directory'};
            app.SimsInQueueTable.RowName = {};
            app.SimsInQueueTable.CellSelectionCallback = createCallbackFcn(app, @SimsInQueueTableCellSelection, true);
            app.SimulationsinQueueLabel = uilabel(app.JobQueueTab);
            app.SimulationsinQueueLabel.Text = 'Simulations in Queue';
            app.StartProcessingServiceButton = uibutton(app.JobQueueTab, 'state');
            app.StartProcessingServiceButton.ValueChangedFcn = createCallbackFcn(app, @InitiateProcessingService, true);
            app.StartProcessingServiceButton.Text = 'Start Processing Service';
            app.RemoveSimJobBtn = uibutton(app.JobQueueTab, 'push');
            app.RemoveSimJobBtn.ButtonPushedFcn = createCallbackFcn(app, @RemoveSimJobBtnButtonPushed, true);
            app.RemoveSimJobBtn.Tooltip = {'Remove selected job from the simulation queue.'};
            app.RemoveSimJobBtn.Text = 'Remove Job';
            app.RefreshButton = uibutton(app.JobQueueTab, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Icon = 'btn_refresh.png';
            app.RefreshButton.Text = 'Refresh';
            app.ProofSimulationsTab = uitab(app.VOETabGroup);
            app.ProofSimulationsTab.Title = '(4) - Proof Simulations';
            app.AvailSimulationsTable = uitable(app.ProofSimulationsTab);
            app.AvailSimulationsTable.ColumnName = {'Patient ID'; 'Simulation Name'; 'Reconstruction Profile'; 'Series Description'; 'Simulation Date'; 'Number of Lesions'};
            app.AvailSimulationsTable.RowName = {};
            app.AvailSimulationsTable.ColumnSortable = true;
            app.AvailSimulationsTable.CellSelectionCallback = createCallbackFcn(app, @AvailSimulationsTableCellSelection, true);
            app.SimulatedPatientStudiesLabel = uilabel(app.ProofSimulationsTab);
            app.SimulatedPatientStudiesLabel.Text = 'Simulated Patient Studies';
            app.SimulationReviewPanel = uipanel(app.ProofSimulationsTab);
            app.SimulationReviewPanel.Title = 'Simulation review';
            app.rlSliceY = uiaxes(app.SimulationReviewPanel);
            app.rlSliceY.XTick = [];
            app.rlSliceY.YTick = [];
            app.rlSliceZ = uiaxes(app.SimulationReviewPanel);
            ylabel(app.rlSliceZ, 'Y')
            app.rlSliceZ.XTick = [];
            app.rlSliceZ.YTick = [];
            app.rlSliceX = uiaxes(app.SimulationReviewPanel);
            app.rlSliceX.XTick = [];
            app.rlSliceX.YTick = [];
            app.Slider_3Label_2 = uilabel(app.SimulationReviewPanel);
            app.Slider_3Label_2.Text = '';
            app.rlColorLimitSlider = uislider(app.SimulationReviewPanel);
            app.rlColorLimitSlider.ValueChangingFcn = createCallbackFcn(app, @rlColorLimitSliderValueChanging, true);
            app.FusionFactorSlider_2Label = uilabel(app.SimulationReviewPanel);
            app.FusionFactorSlider_2Label.Text = 'Fusion Factor';
            app.rlFusionFactorSlider = uislider(app.SimulationReviewPanel);
            app.rlFusionFactorSlider.Limits = [0 1];
            app.rlFusionFactorSlider.MajorTicks = [0 0.5 1];
            app.rlFusionFactorSlider.MajorTickLabels = {'CT', 'Even', 'PET'};
            app.rlFusionFactorSlider.ValueChangedFcn = createCallbackFcn(app, @rlFusionFactorSliderValueChanged, true);
            app.rlFusionFactorSlider.MinorTicks = [0.1 0.2 0.3 0.4 0.6 0.7 0.8 0.9];
            app.rlFusionFactorSlider.Value = 0.5;
            app.PETColormapDropDown_2Label = uilabel(app.SimulationReviewPanel);
            app.PETColormapDropDown_2Label.Text = 'PET Colormap';
            app.rlPETColormapDropDown = uidropdown(app.SimulationReviewPanel);
            app.rlPETColormapDropDown.Items = {'Hot Metal', 'Hot', 'Parula', 'Jet', 'HSV', 'Red White Blue'};
            app.rlPETColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @rlPETColormapDropDownValueChanged, true);
            app.rlPETColormapDropDown.Value = 'Hot';
            app.RefROITable = uitable(app.ProofSimulationsTab);
            app.RefROITable.ColumnName = {'Name'; 'Location'; 'Shape'; 'Parameters'};
            app.RefROITable.RowName = {};
            app.RefROITable.ColumnSortable = [true true true false];
            app.RefROITable.CellSelectionCallback = createCallbackFcn(app, @RefROITableCellSelection, true);
            app.LesionTable = uitable(app.ProofSimulationsTab);
            app.LesionTable.ColumnName = {'Name'; 'Location'; 'PT int. mode'; 'PT int. val'; 'CT val.'; 'Shape'; 'PT add. mode'; 'Parameters'};
            app.LesionTable.RowName = {};
            app.LesionTable.ColumnSortable = [true false true true true true false false];
            app.LesionTable.CellSelectionCallback = createCallbackFcn(app, @LesionTableCellSelection, true);
            app.ReferenceROIsLabel = uilabel(app.ProofSimulationsTab);
            app.ReferenceROIsLabel.Text = 'Reference ROIs';
            app.LesionsLabel = uilabel(app.ProofSimulationsTab);
            app.LesionsLabel.Text = 'Lesions';
            app.OpenfilelocationButton = uibutton(app.ProofSimulationsTab, 'push');
            app.OpenfilelocationButton.ButtonPushedFcn = createCallbackFcn(app, @OpenfilelocationButtonPushed, true);
            app.OpenfilelocationButton.Text = 'Open file location';
            app.LocationunitsDropDownLabel = uilabel(app.ProofSimulationsTab);
            app.LocationunitsDropDownLabel.Text = 'Location units';
            app.LocationunitsDropDown = uidropdown(app.ProofSimulationsTab);
            app.LocationunitsDropDown.Items = {'pixel', 'relative fraction', 'mm from center', 'mm from origin'};
            app.LocationunitsDropDown.ValueChangedFcn = createCallbackFcn(app, @LocationunitsDropDownValueChanged, true);
            app.LocationunitsDropDown.Value = 'mm from center';
            app.LesionParamsCopyToClipboardButton = uibutton(app.ProofSimulationsTab, 'push');
            app.LesionParamsCopyToClipboardButton.ButtonPushedFcn = createCallbackFcn(app, @LesionParamsCopyToClipboardButtonPushed, true);
            app.LesionParamsCopyToClipboardButton.Text = 'Copy to Clipboard';
            app.SimulationSendToDICOMButton = uibutton(app.ProofSimulationsTab, 'push');
            app.SimulationSendToDICOMButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationSendToDICOMButtonPushed, true);
            app.SimulationSendToDICOMButton.Icon = 'exportIcon.png';
            app.SimulationSendToDICOMButton.Text = 'Send to DICOM';
            app.DownloadlesionsummarytableButton = uibutton(app.ProofSimulationsTab, 'push');
            app.DownloadlesionsummarytableButton.ButtonPushedFcn = createCallbackFcn(app, @DownloadlesionsummarytableButtonPushed, true);
            app.DownloadlesionsummarytableButton.Icon = 'downloadIcon.png';
            app.DownloadlesionsummarytableButton.Text = 'Download lesion summary table';
            app.RetrieveLesionsButton = uibutton(app.ProofSimulationsTab, 'push');
            app.RetrieveLesionsButton.ButtonPushedFcn = createCallbackFcn(app, @RetrieveLesionsButtonPushed, true);
            app.RetrieveLesionsButton.Text = 'Retrieve Lesions';
            app.ConfigureTab = uitab(app.HeadTabGroup);
            app.ConfigureTab.Tooltip = {'Configure this application.'};
            app.ConfigureTab.Title = 'Configure';
            app.ConfigureTabGroup = uitabgroup(app.ConfigureTab);
            app.GeneralTab = uitab(app.ConfigureTabGroup);
            app.GeneralTab.Tooltip = {'Settings general to the LST for all users. Amin rights required.'};
            app.GeneralTab.Title = 'General';
            app.ScannerSettingsPanel = uipanel(app.GeneralTab);
            app.ScannerSettingsPanel.Title = 'Scanner Settings (for Data Import)';
            app.addScannerButton = uibutton(app.ScannerSettingsPanel, 'push');
            app.addScannerButton.ButtonPushedFcn = createCallbackFcn(app, @addScannerButtonButtonPushed, true);
            app.addScannerButton.Icon = 'btn_plusIcon.png';
            app.addScannerButton.Text = '';
            app.deleteScannerButton = uibutton(app.ScannerSettingsPanel, 'push');
            app.deleteScannerButton.ButtonPushedFcn = createCallbackFcn(app, @deleteScannerButtonButtonPushed, true);
            app.deleteScannerButton.Icon = 'btn_garbage.png';
            app.deleteScannerButton.Text = '';
            app.ScannerParamsTable = uitable(app.ScannerSettingsPanel);
            app.ScannerParamsTable.ColumnName = {'Name'; 'IP'; 'User Name'; 'Password'; 'Data Path'};
            app.ScannerParamsTable.RowName = {};
            app.ScannerParamsTable.ColumnSortable = [true false false false false];
            app.ScannerParamsTable.ColumnEditable = true;
            app.ScannerParamsTable.CellEditCallback = createCallbackFcn(app, @ScannerParamsTableCellEdit, true);
            app.ScannerParamsTable.CellSelectionCallback = createCallbackFcn(app, @ScannerParamsTableCellSelection, true);
            app.UserAccountsPanel = uipanel(app.GeneralTab);
            app.UserAccountsPanel.Title = 'User Accounts';
            app.UserAccountsTable = uitable(app.UserAccountsPanel);
            app.UserAccountsTable.ColumnName = {'Full Name'; 'Account Name'; 'Password'; 'Administrator'; 'Project'};
            app.UserAccountsTable.RowName = {};
            app.UserAccountsTable.ColumnSortable = [true true false true false];
            app.UserAccountsTable.ColumnEditable = [true false true true true];
            app.UserAccountsTable.CellEditCallback = createCallbackFcn(app, @UserAccountsTableCellEdit, true);
            app.UserAccountsTable.CellSelectionCallback = createCallbackFcn(app, @UserAccountsTableCellSelection, true);
            app.addUserButton = uibutton(app.UserAccountsPanel, 'push');
            app.addUserButton.ButtonPushedFcn = createCallbackFcn(app, @addUserButtonPushed, true);
            app.addUserButton.Icon = 'btn_plusIcon.png';
            app.addUserButton.Text = '';
            app.deleteUserButton = uibutton(app.UserAccountsPanel, 'push');
            app.deleteUserButton.ButtonPushedFcn = createCallbackFcn(app, @deleteUserButtonPushed, true);
            app.deleteUserButton.Icon = 'btn_garbage.png';
            app.deleteUserButton.Text = '';
            app.ReconstructionServerPanel = uipanel(app.GeneralTab);
            app.ReconstructionServerPanel.Title = 'Reconstruction Server';
            app.ImageReconQueueBrowseButton = uibutton(app.ReconstructionServerPanel, 'push');
            app.ImageReconQueueBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @ImageReconQueueBrowseButtonPushed, true);
            app.ImageReconQueueBrowseButton.Text = 'Browse';
            app.ImageReconQueueEditFieldLabel = uilabel(app.ReconstructionServerPanel);
            app.ImageReconQueueEditFieldLabel.Text = 'Image Recon. Queue';
            app.ImageReconQueueEditField = uieditfield(app.ReconstructionServerPanel, 'text');
            app.ImageReconQueueEditField.ValueChangedFcn = createCallbackFcn(app, @ImageReconQueueEditFieldValueChanged, true);
            app.SimulationServerPanel = uipanel(app.GeneralTab);
            app.SimulationServerPanel.Title = 'Simulation Server';
            app.SimulationQueueBrowseButton = uibutton(app.SimulationServerPanel, 'push');
            app.SimulationQueueBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationQueueBrowseButtonPushed, true);
            app.SimulationQueueBrowseButton.Text = 'Browse';
            app.SimulationQueueEditFieldLabel = uilabel(app.SimulationServerPanel);
            app.SimulationQueueEditFieldLabel.Text = 'Simulation Queue';
            app.SimulationQueueEditField = uieditfield(app.SimulationServerPanel, 'text');
            app.SimulationQueueEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationQueueEditFieldValueChanged, true);
            app.SaveGeneralSettings = uibutton(app.GeneralTab, 'push');
            app.SaveGeneralSettings.ButtonPushedFcn = createCallbackFcn(app, @SaveGeneralSettingsButtonPushed, true);
            app.SaveGeneralSettings.Icon = 'btn_saveicon.png';
            app.SaveGeneralSettings.Text = '';
            app.ProjectTab = uitab(app.ConfigureTabGroup);
            app.ProjectTab.Title = 'Project';
            app.ProjectslistforuserPanel = uipanel(app.ProjectTab);
            app.ProjectslistforuserPanel.Title = 'Projects list for user';
            app.ProjectsListBox = uilistbox(app.ProjectslistforuserPanel);
            app.ProjectsListBox.ValueChangedFcn = createCallbackFcn(app, @ProjectsListBoxValueChanged, true);
            app.AddProjectButton = uibutton(app.ProjectslistforuserPanel, 'push');
            app.AddProjectButton.ButtonPushedFcn = createCallbackFcn(app, @AddProjectButtonPushed, true);
            app.AddProjectButton.Icon = 'plusIcon_transp.png';
            app.AddProjectButton.Text = '';
            app.DeleteProjectButton = uibutton(app.ProjectslistforuserPanel, 'push');
            app.DeleteProjectButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteProjectButtonPushed, true);
            app.DeleteProjectButton.Icon = 'btn_garbage.png';
            app.DeleteProjectButton.Text = '';
            app.ShowProjectsDirButton = uibutton(app.ProjectslistforuserPanel, 'push');
            app.ShowProjectsDirButton.ButtonPushedFcn = createCallbackFcn(app, @ShowProjectsDirButtonPushed, true);
            app.ShowProjectsDirButton.Text = 'Show location';
            app.ProjectParmetersPanel = uipanel(app.ProjectTab);
            app.ProjectParmetersPanel.Title = 'Project Name Parmeters';
            app.StorageSettingsPanel = uipanel(app.ProjectParmetersPanel);
            app.StorageSettingsPanel.Title = 'Storage Settings';
            app.RawImportArchiveEditFieldLabel = uilabel(app.StorageSettingsPanel);
            app.RawImportArchiveEditFieldLabel.Text = 'Raw Import Archive';
            app.RawImportArchiveEditField = uieditfield(app.StorageSettingsPanel, 'text');
            app.RawImportArchiveEditField.ValueChangedFcn = createCallbackFcn(app, @RawImportArchiveEditFieldValueChanged, true);
            app.ImageReconArchiveEditFieldLabel = uilabel(app.StorageSettingsPanel);
            app.ImageReconArchiveEditFieldLabel.Text = 'Image Recon. Archive';
            app.ImageReconArchiveEditField = uieditfield(app.StorageSettingsPanel, 'text');
            app.ImageReconArchiveEditField.ValueChangedFcn = createCallbackFcn(app, @ImageReconArchiveEditFieldValueChanged, true);
            app.SimulationArchiveEditFieldLabel = uilabel(app.StorageSettingsPanel);
            app.SimulationArchiveEditFieldLabel.Text = 'Simulation Archive';
            app.SimulationArchiveEditField = uieditfield(app.StorageSettingsPanel, 'text');
            app.SimulationArchiveEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationArchiveEditFieldValueChanged, true);
            app.RawImportBrowseButton = uibutton(app.StorageSettingsPanel, 'push');
            app.RawImportBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @RawImportBrowseButtonPushed, true);
            app.RawImportBrowseButton.Text = 'Browse';
            app.ImageReconArchiveBrowseButton = uibutton(app.StorageSettingsPanel, 'push');
            app.ImageReconArchiveBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @ImageReconArchiveBrowseButtonPushed, true);
            app.ImageReconArchiveBrowseButton.Text = 'Browse';
            app.SimulationArchiveBrowseButton = uibutton(app.StorageSettingsPanel, 'push');
            app.SimulationArchiveBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @SimulationArchiveBrowseButtonPushed, true);
            app.SimulationArchiveBrowseButton.Text = 'Browse';
            app.DICOMSendPanel = uipanel(app.StorageSettingsPanel);
            app.DICOMSendPanel.Title = 'DICOM Send';
            app.TargetHostNameEditFieldLabel = uilabel(app.DICOMSendPanel);
            app.TargetHostNameEditFieldLabel.Text = 'Target Host Name';
            app.DICOMSendTargetHostNameEditField = uieditfield(app.DICOMSendPanel, 'text');
            app.TargetAETEditFieldLabel = uilabel(app.DICOMSendPanel);
            app.TargetAETEditFieldLabel.Text = 'Target AET';
            app.DICOMSendTargetAETEditField = uieditfield(app.DICOMSendPanel, 'text');
            app.SourceAETEditFieldLabel = uilabel(app.DICOMSendPanel);
            app.SourceAETEditFieldLabel.Text = 'Source AET';
            app.DICOMSendSourceAETEditField = uieditfield(app.DICOMSendPanel, 'text');
            app.DICOMSendIncludeCTCheckBox = uicheckbox(app.DICOMSendPanel);
            app.DICOMSendIncludeCTCheckBox.Text = 'Include CT';
            app.TargetPortEditFieldLabel = uilabel(app.DICOMSendPanel);
            app.TargetPortEditFieldLabel.Text = 'Target Port';
            app.DICOMSendTargetPortEditField = uieditfield(app.DICOMSendPanel, 'numeric');
            app.DICOMSendTargetPortEditField.Limits = [1 99999];
            app.DICOMSendTargetPortEditField.RoundFractionalValues = 'on';
            app.DICOMSendTargetPortEditField.ValueDisplayFormat = '%5.0f';
            app.DICOMSendTargetPortEditField.Value = 104;
            app.NotificationservicesPanel = uipanel(app.ProjectParmetersPanel);
            app.NotificationservicesPanel.Title = 'Notification services';
            app.PushbulletTockenEditFieldLabel = uilabel(app.NotificationservicesPanel);
            app.PushbulletTockenEditFieldLabel.Text = 'Pushbullet Tocken';
            app.PushbulletTockenEditField = uieditfield(app.NotificationservicesPanel, 'text');
            app.PushbulletTockenEditField.ValueChangedFcn = createCallbackFcn(app, @PushbulletTockenEditFieldValueChanged, true);
            app.GotoPushbulletwebsiteButton = uibutton(app.NotificationservicesPanel, 'push');
            app.GotoPushbulletwebsiteButton.ButtonPushedFcn = createCallbackFcn(app, @GotoPushbulletwebsiteButtonPushed, true);
            app.GotoPushbulletwebsiteButton.Text = 'Go to Pushbullet website';
            app.TestPushbulletButton = uibutton(app.NotificationservicesPanel, 'push');
            app.TestPushbulletButton.ButtonPushedFcn = createCallbackFcn(app, @TestPushbulletButtonPushed, true);
            app.TestPushbulletButton.Text = 'Test Pushbullet';
            app.ReconstructionOptionsPanel = uipanel(app.ProjectParmetersPanel);
            app.ReconstructionOptionsPanel.Title = 'Reconstruction Options';
            app.ReconProtocolsDirBrowseButton = uibutton(app.ReconstructionOptionsPanel, 'push');
            app.ReconProtocolsDirBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @ReconProtocolsDirBrowseButtonPushed, true);
            app.ReconProtocolsDirBrowseButton.Text = 'Browse';
            app.ReconstructionProtocolDirectoryEditFieldLabel = uilabel(app.ReconstructionOptionsPanel);
            app.ReconstructionProtocolDirectoryEditFieldLabel.Text = 'Reconstruction Protocol Directory';
            app.ReconProtocolsDirEditField = uieditfield(app.ReconstructionOptionsPanel, 'text');
            app.ReconProtocolsDirEditField.ValueChangedFcn = createCallbackFcn(app, @ReconProtocolsDirEditFieldValueChanged, true);
            app.DefaultReconstructionProtocolDropDownLabel = uilabel(app.ReconstructionOptionsPanel);
            app.DefaultReconstructionProtocolDropDownLabel.Text = 'Default Reconstruction Protocol';
            app.DefaultReconProtocolDropDown = uidropdown(app.ReconstructionOptionsPanel);
            app.DefaultReconProtocolDropDown.ValueChangedFcn = createCallbackFcn(app, @DefaultReconProtocolDropDownValueChanged, true);
            app.SaveProjectSettings = uibutton(app.ProjectParmetersPanel, 'push');
            app.SaveProjectSettings.ButtonPushedFcn = createCallbackFcn(app, @SaveProjectSettingsButtonPushed, true);
            app.SaveProjectSettings.Icon = 'btn_saveicon.png';
            app.SaveProjectSettings.Text = '';
            app.CommentsTextAreaLabel = uilabel(app.ProjectParmetersPanel);
            app.CommentsTextAreaLabel.Text = 'Comments';
            app.ProjectCommentsTextArea = uitextarea(app.ProjectParmetersPanel);
            app.DisplaySettingsPanel = uipanel(app.ProjectParmetersPanel);
            app.DisplaySettingsPanel.Title = 'Display Settings';
            app.ImageUnitsDropDownLabel = uilabel(app.DisplaySettingsPanel);
            app.ImageUnitsDropDownLabel.Text = 'Image Units';
            app.ImageUnitsDropDown = uidropdown(app.DisplaySettingsPanel);
            app.ImageUnitsDropDown.Items = {'Activity [Bq/cc]', 'SUV-bw [g/mL]'};
            app.ImageUnitsDropDown.Value = 'Activity [Bq/cc]';
            app.ImageCoordinateUnitsDropDownLabel = uilabel(app.DisplaySettingsPanel);
            app.ImageCoordinateUnitsDropDownLabel.Text = 'Image Coordinate Units';
            app.ImageCoordinateUnitsDropDown = uidropdown(app.DisplaySettingsPanel);
            app.ImageCoordinateUnitsDropDown.Items = {'pixel', 'relative fraction', 'mm from center', 'mm from origin'};
            app.ImageCoordinateUnitsDropDown.Value = 'pixel';
            app.DatalabellingPanel = uipanel(app.ProjectParmetersPanel);
            app.DatalabellingPanel.Title = 'Data labelling';
            app.SimulationNameTemplateExplationText = uilabel(app.DatalabellingPanel);
            app.SimulationNameTemplateExplationText.Text = 'Use the simulation name template to automatically name simulation results. Use text and the following keys: ';
            app.SimulationNameTemplateEditFieldLabel = uilabel(app.DatalabellingPanel);
            app.SimulationNameTemplateEditFieldLabel.Text = 'Simulation Name Template';
            app.SimulationNameTemplateEditField = uieditfield(app.DatalabellingPanel, 'text');
            app.SeriesDescriptionEditFieldLabel = uilabel(app.DatalabellingPanel);
            app.SeriesDescriptionEditFieldLabel.Text = 'Series Description';
            app.SeriesDescriptionEditField = uieditfield(app.DatalabellingPanel, 'text');
            app.ProjectsExplanationText = uilabel(app.ProjectTab);
            app.ProjectsExplanationText.Text = 'Projects are saved under each user''s profile in a directory structure.';
            app.FavoriteProjectButton = uibutton(app.ProjectTab, 'push');
            app.FavoriteProjectButton.ButtonPushedFcn = createCallbackFcn(app, @FavoriteProjectButtonPushed, true);
            app.FavoriteProjectButton.Icon = 'starIcon_transp.png';
            app.FavoriteProjectButton.Tooltip = {'Make this my default project.'};
            app.FavoriteProjectButton.Text = '';
            app.ReconTab = uitab(app.ConfigureTabGroup);
            app.ReconTab.Title = 'Recon';
            app.ProfileParametersPanel = uipanel(app.ReconTab);
            app.ProfileParametersPanel.Title = 'Profile Parameters';
            app.ReconProfileParamasTable = uitable(app.ProfileParametersPanel);
            app.ReconProfileParamasTable.ColumnName = {'IR Parameter'; 'Value'};
            app.ReconProfileParamasTable.RowName = {};
            app.ReconProfileParamasTable.ColumnEditable = [false true];
            app.ReconProfileParamasTable.CellEditCallback = createCallbackFcn(app, @ReconProfileParamasTableCellEdit, true);
            app.SaveProfileButton = uibutton(app.ProfileParametersPanel, 'push');
            app.SaveProfileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveProfileButtonPushed, true);
            app.SaveProfileButton.Icon = 'btn_saveicon.png';
            app.SaveProfileButton.Tooltip = {'save'};
            app.SaveProfileButton.Text = '';
            app.RestoreReconProfileParamsButton = uibutton(app.ProfileParametersPanel, 'push');
            app.RestoreReconProfileParamsButton.ButtonPushedFcn = createCallbackFcn(app, @RestoreReconProfileParamsButtonPushed, true);
            app.RestoreReconProfileParamsButton.Icon = 'btn_restoreicon.png';
            app.RestoreReconProfileParamsButton.Tooltip = {'Restore default'};
            app.RestoreReconProfileParamsButton.Text = '';
            app.AddReconProfileButton = uibutton(app.ReconTab, 'push');
            app.AddReconProfileButton.ButtonPushedFcn = createCallbackFcn(app, @AddReconProfileButtonPushed, true);
            app.AddReconProfileButton.Icon = 'plusIcon_transp.png';
            app.AddReconProfileButton.Tooltip = {'Add new profile'};
            app.AddReconProfileButton.Text = '';
            app.DeleteReconProfileButton = uibutton(app.ReconTab, 'push');
            app.DeleteReconProfileButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteReconProfileButtonPushed, true);
            app.DeleteReconProfileButton.Icon = 'minusIcon_transp.png';
            app.DeleteReconProfileButton.Tooltip = {'Delete profile'};
            app.DeleteReconProfileButton.Text = '';

            app.ProfileNamesListBoxLabel = uilabel(app.ReconTab);
            app.ProfileNamesListBoxLabel.Text = 'Profile Names';
            app.ReconProfileNamesListBox = uilistbox(app.ReconTab);
            app.ReconProfileNamesListBox.ValueChangedFcn = createCallbackFcn(app, @ReconProfileNamesListBoxValueChanged, true);
            app.ReconTabExplanationText = uilabel(app.ReconTab);
            app.ReconTabExplanationText.Text = {'Preconfigure image reconstruction profiles for raw and simulations data.'; 'The library is selected in the Project configuration tab.'; 'Profile names should be unique.'};
            app.ReconstructionProtocolLibraryNameLabel = uilabel(app.ReconTab);
            app.ReconstructionProtocolLibraryNameLabel.Text = 'Reconstruction Protocol Library Name';
            app.SystemIDTextbox = uitextarea(app.RightPanel);
            app.SystemIDTextbox.Editable = 'off';
            app.SystemIDTextbox.Enable = 'off';
            app.SystemIDTextbox.Value = {'Version 1.0  - The Ottawa Hospital Division of Nuclear Medicine'};
            app.MessageLampLabel = uilabel(app.RightPanel);
            app.MessageLampLabel.Text = 'Workstation Locked';
            app.MessageLamp = uilamp(app.RightPanel);
            app.ProcessingServerRunningLampLabel = uilabel(app.RightPanel);
            app.ProcessingServerRunningLampLabel.Text = 'Processing Server Running';
            app.ProcessingServerRunningLamp = uilamp(app.RightPanel);
            % Show the figure after all components are created
            app.LesionSynthesisToolboxUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LesionSynthesisToolbox

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LesionSynthesisToolboxUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LesionSynthesisToolboxUIFigure)
        end
    end
end